classdef FlyGroomAv2_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FlyGroomAFlyGroomingAnalysisLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        BoxplotsTab                   matlab.ui.container.Tab
        StatisticsDropDown            matlab.ui.control.DropDown
        StatisticsDropDownLabel       matlab.ui.control.Label
        SavePathEditField             matlab.ui.control.EditField
        SavePathEditFieldLabel        matlab.ui.control.Label
        SetButton                     matlab.ui.control.Button
        PlotBox                       matlab.ui.control.Button
        ExportdatatoExcelCheckBox     matlab.ui.control.CheckBox
        GraphDropDown                 matlab.ui.control.DropDown
        GraphDropDownLabel            matlab.ui.control.Label
        ControlDropDown               matlab.ui.control.DropDown
        ControlDropDownLabel          matlab.ui.control.Label
        GroupingDropDown              matlab.ui.control.DropDown
        GroupingDropDownLabel         matlab.ui.control.Label
        DatatoPlotPanel               matlab.ui.container.Panel
        SelectBehaviorDropDown        matlab.ui.control.DropDown
        YMaxLabel                     matlab.ui.control.Label
        Ymax_tot                      matlab.ui.control.NumericEditField
        Ymax_per                      matlab.ui.control.NumericEditField
        Ymax_dur                      matlab.ui.control.NumericEditField
        Ymax_freq                     matlab.ui.control.NumericEditField
        YMinLabel                     matlab.ui.control.Label
        Ymin_tot                      matlab.ui.control.NumericEditField
        Ymin_per                      matlab.ui.control.NumericEditField
        Ymin_dur                      matlab.ui.control.NumericEditField
        Ymin_freq                     matlab.ui.control.NumericEditField
        MetricBox                     matlab.ui.control.Label
        TotalTimeCheckBox             matlab.ui.control.CheckBox
        PercentTimeCheckBox           matlab.ui.control.CheckBox
        BoutDurationCheckBox          matlab.ui.control.CheckBox
        BoutFrequencyCheckBox         matlab.ui.control.CheckBox
        ResetAll                      matlab.ui.control.Button
        Reset_tot                     matlab.ui.control.Button
        Reset_per                     matlab.ui.control.Button
        Reset_dur                     matlab.ui.control.Button
        Reset_freq                    matlab.ui.control.Button
        HistogramsTab                 matlab.ui.container.Tab
        PlotHistogram                 matlab.ui.control.Button
        StimulusPanel                 matlab.ui.container.Panel
        StimTEditField                matlab.ui.control.NumericEditField
        TLabel                        matlab.ui.control.Label
        removeStim                    matlab.ui.control.Button
        addStim                       matlab.ui.control.Button
        StimulusColorLabel            matlab.ui.control.Label
        StimColorLamp                 matlab.ui.control.Lamp
        StimBEditField                matlab.ui.control.NumericEditField
        BEditFieldLabel               matlab.ui.control.Label
        StimGEditField                matlab.ui.control.NumericEditField
        GEditFieldLabel               matlab.ui.control.Label
        StimREditField                matlab.ui.control.NumericEditField
        REditFieldLabel               matlab.ui.control.Label
        StimulustimessecLabel         matlab.ui.control.Label
        stimtimes                     matlab.ui.control.Table
        PlotStimulusCheckBox          matlab.ui.control.CheckBox
        SmoothingsecEditField         matlab.ui.control.NumericEditField
        SmoothingsecEditFieldLabel    matlab.ui.control.Label
        FrameRatefpsEditField         matlab.ui.control.NumericEditField
        FrameRatefpsEditFieldLabel    matlab.ui.control.Label
        EthogramsTab                  matlab.ui.container.Tab
        StimBEditField_etho           matlab.ui.control.NumericEditField
        BEditFieldLabel_2             matlab.ui.control.Label
        StimGEditField_etho           matlab.ui.control.NumericEditField
        GEditFieldLabel_2             matlab.ui.control.Label
        StimREditField_etho           matlab.ui.control.NumericEditField
        REditFieldLabel_2             matlab.ui.control.Label
        StimColorLamp_etho            matlab.ui.control.Lamp
        StimulusColorLabel_etho       matlab.ui.control.Label
        PlotStimulusCheckBox_etho     matlab.ui.control.CheckBox
        EthogramtypeDropDown          matlab.ui.control.DropDown
        EthogramtypeDropDownLabel     matlab.ui.control.Label
        PlotEthogram                  matlab.ui.control.Button
        MatFilesPanel                 matlab.ui.container.Panel
        ProcesstxtFilesButton         matlab.ui.control.Button
        RemovefilesButton             matlab.ui.control.Button
        FilesListBox                  matlab.ui.control.ListBox
        FilesListBoxLabel             matlab.ui.control.Label
        AddfilesButton                matlab.ui.control.Button
        BehaviorsListBox              matlab.ui.control.ListBox
        BehaviorsListBoxLabel         matlab.ui.control.Label
        TimeRangesecLabel             matlab.ui.control.Label
        EndEditField                  matlab.ui.control.NumericEditField
        EndEditFieldLabel             matlab.ui.control.Label
        StartEditField                matlab.ui.control.NumericEditField
        StartLabel                    matlab.ui.control.Label
        GenotypeEditField             matlab.ui.control.EditField
        GenotypeEditFieldLabel        matlab.ui.control.Label
        PlotselectionPanel            matlab.ui.container.Panel
        BinCheckBox                   matlab.ui.control.CheckBox
        RemoveStim                    matlab.ui.control.Button
        AddStim                       matlab.ui.control.Button
        Bins                          matlab.ui.control.Table
        StartBuffersecEditField       matlab.ui.control.NumericEditField
        StartBuffersecEditFieldLabel  matlab.ui.control.Label
        TimeRangesecLabel_2           matlab.ui.control.Label
        CommonEndLabel                matlab.ui.control.Label
        CommonEnd                     matlab.ui.control.NumericEditField
        CommonStartLabel              matlab.ui.control.Label
        CommonStart                   matlab.ui.control.NumericEditField
        BehaviorstoplotList           matlab.ui.control.ListBox
        BehaviorstoplotListBoxLabel   matlab.ui.control.Label
        GenotypestoplotList           matlab.ui.control.ListBox
        GenotypestoplotListBoxLabel   matlab.ui.control.Label
    end


    properties (Access = public)
        data    % data from matfiles the user has selected
        matIdx  % index of currently selected mat file
        files   % files to analyze
        genotypes % genotypes of selected experiments
        genos2plot  % genotypes to plot
        genoIdx     % index to pull genotypes to plot from data
        behaviors % common behaviors
        behavs2plot % behaviors to plot
        behavIdx    % index to pull behaviors to plot from data
        timerange   % start and end of time range to plot (sec)
        timerangelimit  % common time range for all experiments (sec)
        dataYrange  % calculated limits for y-axis of graphs (table: Metric, Behav, Ymin, Ymax)
        currYrange  % current limits for y-axis of graphs (table: Metric, Behav, Ymin, Ymax)
        plotgrouping = 'Behavior';      % how to group data in plots
        plotcontrol = 'None';        % control to use for plots
        graphtype       % type of graph to plot (box or bar)
        stats  = 'None';         % what kind of stats to do (para, non-para, none)
        plotbinning = 'Total';     % whether to plot total data or binned data
        plotmetrics = {};     % what metric to plot (percent time, total time, bout frequency, bout duration)
        updatemat       % update mat files
        absdiff         % plot absdiff from control
        export2excel    % export data to excel
        plotstim         % plot stimuli on Histogram
        stimcolor = [0 0 0]      % color of stimulation bars
        stimtimes_all       % Start and dur (sec) of stimulations times across all files
        stimtimes_single    % Start and dur (sec) of stimulations times for a single expt
        fps             % frames per minute
        win  % window for smooting histograms
        behaviorClasses % behavior classes defined from param file
        behavTable      % table of behaviors and colors from param file
        behavList       % list of behaviors defined from param file
        ethogram_type = 'Compact';  % style of ethogram to plot (compact, expanded, initial bout)
        xlsSavePath = '' % Path where xls file will be saved
        stim_name = ''; % name of stimulus from behaviors list
        start_buffer = 20; % time in sec before 1st stimulus to set as 0 for an experiment
        paramsTable = []; % table from params.csv file
        paramsFName = ''; % name and path to params.csv file
        stimAlpha = 0.25; % transparency of stimulus in histogram. Value must be between [0 1]

    end

    methods (Access = private)

        function update_matfile_info(app)
            % update genotype field
            app.GenotypeEditField.Value = app.data{app.matIdx}.genotype;

            % update Time range
            time_range = app.data{app.matIdx}.timeRange;
            app.StartEditField.Value = time_range(1);
            app.EndEditField.Value = time_range(2);

            % update Behaviors
            behaviors = fieldnames(app.data{app.matIdx}.events);

            behaviors = behaviors(strcmp(behaviors,'file_path')==0); % remove 'file_path' from list of behaviors
            app.BehaviorsListBox.Items = behaviors;

        end

        function updatecommonfeats(app)
            % This function updates all common features in boxplot tab from
            % data files

            % get combined behaviors
            % Edit 9/17/24 - If behavs2plot is populated from params, isn't
            % this unneccessary?
            % behaviors = fieldnames(app.data{1}.events);
            % for i = 2:length(app.data)
            %     %behaviors = intersect(behaviors, fieldnames(app.data{i}.events)); % make this a union (all behaviors)
            %     behaviors = union(behaviors, fieldnames(app.data{i}.events)); % make this a union (all behaviors)
            % end
            %
            % % The behaviors should be checked against params file
            % % incorrect_behavs = setdiff(behaviors,app.behavList);
            %
            % % Finds 'stimulus on' behavior and eliminates it from list
            % if ~isempty(ismember({'file_path' 'stimulus_on'},behaviors)) % change this to user-specified name for stimulus_on
            %     keepIdx = ones(1,length(behaviors));
            %     keepIdx(strcmp('file_path',behaviors)) = 0;
            %     keepIdx(strcmp('stimulus_on',behaviors)) = 0;
            %     keepIdx = logical(keepIdx);
            %     behaviors = behaviors(keepIdx);
            % end
            % app.behaviors = behaviors;
            % app.BehaviorstoplotList.Items = behaviors; % update possible behaviors in boxplots
            % %app.BehaviorstoplotListHist.Items = behaviors; % update possible behaviors in histogram

            % import params and populate GUI
            %[app.behaviorClasses, app.behavTable, app.behavList] = import_behavparams(app,bdirName,bfileName);
            [app.behaviorClasses, app.behavTable, app.behavList] = get_params(app);

            %app.behaviorClasses = behaviorClasses;
            %app.behavTable = B;
            %app.behavList = BList;


            % Find stimulus on and eliminate from list
            stim_idx = find(app.behavTable.BehaviorClass=='class_stim');
            if ~isempty(stim_idx)
                app.stim_name = app.behavTable.Behavior{stim_idx}; % name of stimulus on

                %create stim_color
                rmidx = strcmp(app.stim_name,app.behavList(:,1));
                %TODO - fix stimcolor
                app.stimcolor = app.behavList{rmidx,2};
                update_stimcolors(app);

                % remove stim_class and stim_name from BList
                app.behavList = app.behavList(~rmidx,:); % remove stim_name line
                app.behavList = app.behavList(~strcmp('class_stim',app.behavList(:,1)),:);
                clear rmidx

            else
                 % if no stimulus was found, deactivate all stimulus features
                app.stim_name = [];
                app.PlotStimulusCheckBox.Enable = 'off';
                app.plotstim = 0;
            end

           
            % update start buffer
            app.StartBuffersecEditField.Value = app.start_buffer;

            % Sort app.behavList alphabetically
            %app.behaviors = sortrows(BList(:,1));

            % populate Behaviors to plot box
            app.behaviors = app.behavList(:,1);
            app.BehaviorstoplotList.Items = app.behaviors;

            % Change color of Load Params Button
            %app.LoadParamsButton.BackgroundColor = [0 1 0];


            % update list of genotypes to plot and stim times
            genos = cell(1,length(app.data));
            %stimtimes = cell(length(app.data),2);
            for i = 1:length(app.data)
                genos{i} = app.data{i}.genotype;
                %stimtimes(i,1) = app.data{i}.timeRange;
            end

            app.genotypes = genos;
            app.GenotypestoplotList.Items = app.genotypes;
            %app.GenotypestoplotListHist.Items = app.genotypes;

            % find minimum time ranges across files
            %             app.timerange = [min(common_timerange(:,1)) min(common_timerange(:,2))];
            app.CommonStart.Value = app.timerange(1);
            %app.CommonStart_2.Value = app.timerange(1);
            app.CommonEnd.Value = app.timerange(2);
            %app.CommonEnd_2.Value = app.timerange(2);

            % update controlgenoslist
            app.ControlDropDown.Items = ['None' app.genotypes];

            % get common stimulation times


        end

        function out = matrixconversion(app,events,behaviors)

            % Inputs
            % events = structure with grooming data

            % fps = frames per second (e.g. 30 fps)
            fps = app.fps;


            % behaviors = behaviors to convert.  Must be same as fieldnames of events
            % structure

            % check to see if behaviors is a cell array or string, if string convert to
            % cell array
            if ~iscell(behaviors)
                behaviors = cellstr(behaviors);
            end

            % get duration in frames
            % duration
            app.timerange;
            timeduration = app.timerange(2)-app.timerange(1);
            numpoints = timeduration * app.fps;

            %need to specify how many rows based on number of flies
            num_flies=size(events, 2);

            % create struct for output
            out = struct;

            for b = 1:length(behaviors)
                %create matrix of flies (rows) by number of time points (columns)
                behav_mat=zeros(num_flies,numpoints);

                for h=1:num_flies
                    data=(events(h).(behaviors{b}));

                    numbouts=size(data,1);
                    timecorrection=min(events(h).(app.stim_name)(:,1))-app.start_buffer; %TODO fix if no stim specified
                    %Note: I think the time correction was something put in
                    %assuming all stimuli start at 20 seconds. Better to
                    %have user input stimuli times and use this as
                    %correction.


                    for i=1:numbouts
                        %initpoint=ceil(data(i,1)/binningduration);
                        initpoint=round((data(i,1)-timecorrection)*fps);
                        %initpoint=round(app.timerange(1) * app.fps);
                        if initpoint==0
                            initpoint=1;
                        end
                        %finalpoint=initpoint + round((data(i,2))*fps);
                        finalpoint = round((data(i,1)+data(i,2)-timecorrection) * app.fps);
                        if finalpoint > length(behav_mat)
                            finalpoint = length(behav_mat);
                        end
                        if initpoint>0
                            behav_mat(h,initpoint:finalpoint)=1;
                        end
                    end

                end

                out.(behaviors{b}) = behav_mat;

            end
        end

        function plothistogram(app)
            %BEHAV_HISTOGRAMS Summary of this function goes here
            %   Detailed explanation goes here

            % pull genotypes2plot
            %             genos2plot = app.genos2plot;
            %             genoIdx = app.genoIdx;

            % pull behaviors2plot
            %             behavs2plot = app.behavs2plot;
            %             behavIdx = app.behavIdx;

            % duration of time to plot (i.e., timerange)
            %timerange = app.timerange; %seconds
            dur = app.timerange(2)-app.timerange(1);

            % fps
            fps = app.FrameRatefpsEditField.Value;

            % smooting window
            win = app.SmoothingsecEditField.Value;

            %% Define colors for plots

            %make sure selected behaviors are in behav_params file
            % To Edit 9/17/24 - if behavs2plot populated from params file,
            % this is unneccessary
            %possible_behavs = [unique(app.behavTable.BehaviorClass); unique(app.behavTable.Behavior)];
            foundbehavs = ismember(app.behavs2plot,app.behaviors);
            % add message to ask user to edit file to add this behavior

            % put together colors for each behavior to plot
            colors2plot = {};
            existing_behavs = app.behavs2plot(foundbehavs);
            for i = 1:length(existing_behavs)
                idx = find(strcmp(app.behavList(:,1),existing_behavs{i})==1);
                colors2plot{i,1} = existing_behavs{i};
                colors2plot{i,2} = app.behavList{idx,2};
            end


            %% Plot each genotype

            % % if a control is specified, sort genos2plot so control is
            % % first
            % if ~strcmp(app.ControlDropDown_Hist.Value,'None')
            % conind = find(contains(app.genos2plot,app.ControlDropDown_Hist.Value)==1);
            % sort_order = [conind setdiff(1:length(app.genos2plot),conind)];
            % hist_genos2plot = app.genos2plot(sort_order);
            % hist_genoIdx = app.genoIdx(sort_order);
            % end

            for i = 1:length(app.genos2plot)

                currEvent = app.data{app.genoIdx(i)}.events;
                currGenotypeName = app.genotypes{i};
                % currEvent = app.data{hist_genoIdx(i)}.events;
                % currGenotypeName = hist_genos2plot{i};

                behav_mat = matrixconversion(app,currEvent,app.behavs2plot);


                % get stimuli & offset
                if app.plotstim==1
                    stim_mat = matrixconversion(app,currEvent,app.stim_name);
                    stim = stim_mat.(app.stim_name);
                    %                     stim1 = zeros(size(stim,1),1);
                    %                     for s = 1:size(stim,1)
                    %                         stim1(s) = find(stim(i,:)==1,1);
                    %                     end
                    %                     offset = min(stim1)-1;
                    %
                    %
                    %                     stim_corr = zeros(size(stim));
                    %                     for s = 1:size(stim1,1)
                    %                         temp = stim(s,stim1(i)-offset(i):end);
                    %                         stim_corr(i,1:length(temp)) = temp;
                    %                     end


                    stim = sum(stim,1);
                    %stim = sum(stim_corr,1);
                    bw = bwconncomp(stim);

                    stim_times = zeros(length(bw.PixelIdxList),2);

                    for s = 1:size(stim_times,1)
                        stim_times(s,:) = [bw.PixelIdxList{s}(1) bw.PixelIdxList{s}(end)];
                    end
                else stim_times = [];
                end


                openFigHandles = findall(0, 'type', 'figure');
                numOpenFigures = numel(openFigHandles);

                figure(numOpenFigures)

                % plot time series histogram for each behavior
                for b = 1:length(app.behavs2plot)
                    idx = find(strcmp(colors2plot(:,1),app.behavs2plot{b})==1);
                    plotcolor = colors2plot{idx,2};
                    %stimcolor = app.stimcolor;
                    if ~isempty(stim_times)
                        if b ==length(app.behavs2plot)
                            %                         hoopfer_timeseries_hist_min(behav_mat,behavs2plot{b},app.win,1/app.fps,genotypename,plotcolor,'line',[0 dur],stim_times/fps,stimcolor,'')
                            plot_timeserieshist(app,behav_mat,app.behavs2plot{b},win,1/fps,currGenotypeName,plotcolor,'line',[0 dur],stim_times/fps,app.stimcolor,'')
                        else
                            plot_timeserieshist(app,behav_mat,app.behavs2plot{b},win,1/fps,currGenotypeName,plotcolor,'line',[0 dur],'','','')
                        end
                        hold on

                    else

                        plot_timeserieshist(app,behav_mat,app.behavs2plot{b},win,1/fps,currGenotypeName,plotcolor,'line',[0 dur],'','','')
                    end
                    hold on

                end
                legend(app.behavs2plot);
            end


        end

        function [behaviorClasses, behavTable, behavList] = import_behavparams(app,dirName,fileName)

            %IMPORT_BEHAVPARAMS This function imports behaviors and colors for grooming
            %plots
            %  Detailed explanation goes here
            % dirName = filepath where csv param file is located
            % fileName = name of csv params file to import

            %% Import csv file and make struct

            T = readtable(fullfile(dirName,fileName));

            T.BehaviorClass = categorical(T.BehaviorClass);

            % get BehaviorClasses and create structure
            behav_classes = cellstr(unique(T.BehaviorClass));    % behavior classes from 1st col

            % reorder behav_classes as specified in ClassOrder
            T = sortrows(T,{'ClassOrder','BehaviorOrder'},{'ascend','ascend'}); % sort rows by class and behavior

            % order_ind = [];
            % for i = 1:length(behav_classes)
            %     order_ind = [order_ind unique(T{T.BehaviorClass==behav_classes{i},'ClassOrder'})];
            % end
            % [order_ind,idx] = sort(order_ind);
            % behav_classes = behav_classes(idx);


            % % Behavior Classes (this will be imported from a params file in future)
            %     behaviorClasses.head_cleaning = {'head_cleaning', 'antennal_cleaning', 'proboscis_cleaning', 'ventral_head'};
            %     behaviorClasses.front_leg_rubbing = {'front_leg_rubbing', 'b_1st_and_2nd_leg_rub', 'front_proximal_leg_rub'};
            %     behaviorClasses.wing_cleaning = {'wing_cleaning', 'ventral_wing', 'wing_blade', 'wing_hinge_slash_haltere'};
            %     behaviorClasses.back_leg_rubbing = {'back_leg_rubbing', 'proximal_leg_rub', 'b_2nd_and_3rd_leg_rub'};
            %     behaviorClasses.abdominal_cleaning = {'abdominal_cleaning', 'genital_cleaning'};
            %     behaviorClasses.other = {'ventral_front_cleaning', 'grooming_attempt', 'first_leg_notum', 'ventral_back_cleaning', ...
            %                              'back_leg_grooming_attempt', 'standing_still', 'on_back', 'slip_slash_jump', 'ventral_middle'};

            behaviorClasses = struct;
            behavList = {};

            for i = 1:length(behav_classes)
                bIdx =  find(T.BehaviorClass==behav_classes{i}==1);
                if ~isempty(bIdx)
                    % get behaviors for this class
                    behavs = T.Behavior(bIdx);                                               % get behaviors belonging to this class
                    %[~,bind] = sort(T.BehaviorOrder(bIdx));     % necessary?                                 % get and sort behavior order
                    %behavs = behavs(bind);                      % necessary?                                   % reorder behaviors according to specified order
                    %behavs = reshape(behavs,1,size(behavs,1));  % necessary?                            % reshape into 1x... array

                    % get colors for behav class & individual behaviors
                    classcolor = [T.R1(bIdx(1)) T.G1(bIdx(1)) T.B1(bIdx(1))];
                    behavcolors = [T.R2(bIdx) T.G2(bIdx) T.B2(bIdx)];
                    %behavcolors = behavcolors(bind,:);  % sort to match behaviors, necessary?

                    % add to behaviorClasses structure
                    behaviorClasses.(behav_classes{i}).classcolor =  classcolor;
                    behaviorClasses.(behav_classes{i}).behaviors = behavs;
                    behaviorClasses.(behav_classes{i}).behaviorcolors = behavcolors;

                    % build temporary BList
                    blist = [];
                    blist{1,1} = behav_classes{i};
                    blist{1,2} = classcolor;
                    blist(2:1+length(behavs),1) = behavs;
                    for ii = 1:length(behavs)
                        blist{ii+1,2} = behavcolors(ii,:);
                    end

                    behavList = [behavList ; blist];

                end

            end

            % find repeated entries in behavList
            [~,stringIdx] = unique(behavList(:,1)); % behavList
            behavList = behavList(stringIdx,:);


            behavTable = T;
        end

        function plot_timeserieshist(app,behav_mat,behavior,bin,frmRate,plot_title,color,type,time_lim,stimtimes,stimcolor,fly)

            % TIMESERIES_HIST plots a histogram of the fraction of flies showing a
            % behavior.  The data can be binned (bin = seconds).
            %hoopfer_timeseries_hist_min(out,'antennal_cleaning',1,1/35,'',[1 0 0],'line',[0 35],[],[],[])
            % Note to fix: Cannot have time window of < 1 sec. Problem with
            % x-axis labels.

            if nargin < 8 || isempty(time_lim)
                time_lim = [];
            end

            if nargin < 9
                stimtimes = [];
                stimcolor = [];
            end


            % load behavior matrix
            X = behav_mat.(behavior);

            if ~isempty(time_lim)
                X = X(:,1+time_lim(1)/frmRate:time_lim(2)/frmRate,:);
            end

            binfrms = round(bin/frmRate);  % convert bin to frames

            if ~isempty(time_lim)
            end


            bins = 0:binfrms:size(X,2);    % calculate bins

            % concatenate flies together
            if nargin < 10 || isempty(fly) % plot one fly only?
                fly = 1:size(X,3);
            end

            all = [];
            for i = fly
                all = [all ; X(:,:,i)];
            end

            maxTime = round(size(X,2)*frmRate);

            % Sum the number of frames/bin that each fly is doing the behavior
            s = zeros(size(all,1),length(bins)-1);

            for i = 1:length(bins)-1
                s(:,i) = sum(all(:,bins(i)+1:bins(i+1)),2);
            end

            % Calculate the fraction of flies performing behavior per bin
            ind = s > 0;
            fraction = sum(ind,1) / size(all,1);

            xticks = round(bins * frmRate);
            % find minutes
            xticks_labels = xticks / 60; % convert to minutes
            xtick_ind = find(rem(xticks_labels,1)==0);
            xticks_labels = xticks_labels(xtick_ind);

            xticks = xticks(2:end);

            switch type
                case 'filled'
                    area(xticks,fraction,'FaceColor',color)
                case 'line'
                    plot(xticks,fraction,'color',color,'linewidth',2)
                case 'bar'
                    h = bar(xticks,fraction);
                    set(h,'facecolor',color);
            end

            % Get axis limits
            xlims = get(gca,'xlim');
            set(gca,'xlim',[0 maxTime]);
            set(gca,'ylim',[0 1]);
            ylims = get(gca,'ylim');

            % Add stimtimes
            % Add stimulation intervals (optional)
            if ~isempty(stimtimes)
                for i = 1:size(stimtimes,1)
                    %         h = patch([stimtimes(i,1) stimtimes(i,1) stimtimes(i,2) stimtimes(i,2)],...
                    %             [0 ylims(2)/50 ylims(2)/50 0],'facecolor',stimcolor,'EdgeColor','none');
                    a = rectangle('Position',[stimtimes(i,1),0,stimtimes(i,2)-stimtimes(i,1),ylims(2)],...
                        'FaceColor',[stimcolor 0.3],'EdgeColor','none');
                    a.FaceAlpha = app.stimAlpha;
                end
            end

            % Fix the xtick_labels to accomodate smoothing window of less
            % than 1 sec
            set(gca,'xtick',[xticks_labels * 60]);
            set(gca,'xticklabel',[xticks_labels]);
            set(gca,'fontsize',14)


            ylabel({'Fraction of flies'; 'performing behavior'},'fontsize',14,'interpreter','none')
            xlabel('Time (min)','fontsize',14)

            legend(app.behavs2plot,'interpreter','none');

            title(plot_title,'fontsize',14,'fontweight','b','interpreter','none')
            box('off')

        end


        function update_stimcolors(app)
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            app.StimREditField.Value = app.stimcolor(1);
            app.StimREditField_etho.Value = app.stimcolor(1);
            app.StimGEditField.Value = app.stimcolor(2);
            app.StimGEditField_etho.Value = app.stimcolor(2);
            app.StimBEditField.Value = app.stimcolor(3);
            app.StimBEditField_etho.Value = app.stimcolor(3);

        end % end of genotype loop


        function results = plot_ethogram(app)
            % PLOT_ETHOGRAM plots a raster graph of behaviors per fly.  Multiple
            % behaviors can be plotted, stimulation intervals can be added.

            % INPUTS
            % LIMITS: minTime and maxTime of x-axis.  If blank goes with 0:max time.
            % COLORs: 0 = go with default colors, 1 = user to define colors
            % STIMTIMES: matrix of stimulation times (Ex. LED light)
            %                Format: [start,dur] in seconds
            % CUSTOMIZE: boolean, true = ask user to select which behaviors to plot and
            % enter title for plot, false = go with defaults
            % SEPARATE: true = plot behaviors on separate lines, false = compress
            % behaviors to one line
            % FLY: specify which fly to plot, must be a number


            %% DEFINE INPUTS AND OPTIONS

            % pull genotypes2plot
            %             genos2plot = app.genos2plot;
            %             genoIdx = app.genoIdx;

            % pull behaviors2plot
            %             behavs2plot = app.behavs2plot;
            %             behavIdx = app.behavIdx;

            % duration of time to plot (i.e., timerange)
            dur = app.timerange(2)-app.timerange(1);

            % Frame rate of movies
            frmRate = 1 / app.fps;

            fileGroupCount = inf;

            %% DEFINE COLORS AND STYLE OF ETHOGRAM

            %make sure selected behaviors are in behav_params file
            possible_behavs = [unique(app.behavTable.BehaviorClass); unique(app.behavTable.Behavior)];
            notfound = ~ismember(app.behavs2plot,possible_behavs);
            excluded_behavs = app.behavs2plot(notfound);
            existing_behavs = app.behavs2plot(~notfound);
            nbehavs = length(existing_behavs); % Number of behaviors to plot

            % exclude missing behaviors and display  message to ask user to edit file to add this behavior
            if ~isempty(excluded_behavs)
                % remove the excluded behavior from apps.behavs2plot list
                for missing = 1:length(excluded_behavs)
                    msgbox(['The following behavior was not found in the file: ' excluded_behavs{missing}],"Error","error")
                end
            end

            % put together colors for each behavior to plot
            colors2plot = {};
            for i = 1:length(existing_behavs)
                idx = find(strcmp(app.behavList(:,1),existing_behavs{i})==1);
                colors2plot{i,1} = existing_behavs{i};
                colors2plot{i,2} = app.behavList{idx,2};
            end

            % Specify style and particular of ethogram
            % Compact: plot all selected behaviors on a line, each line is a
            % different fly
            % Expanded: plot each behavior on a separate line, plot only shows one
            % fly

            % Style
            style = lower(app.ethogram_type); % compact or expanded

            % if expanded is selected, user must select which flies to plot


            % Constants for ethogram plot (This could be specified in GUI)
            barHeight = 50;        % the height of each behavior's bar
            barSep = 20;             % the amount of vertical separation between each bar
            timePad = 2;   % the amount of padding to add to the left and right side of the plot (seconds)
            stimulusHeight = 6;
            stimulusSep = 6;


            %% Plot ethogram for each genotype

            for i = 1:length(app.genos2plot) % loop through each genotype selected
                % each genotype will be plotted on a separate figure

                % Get and organize data for each genotype

                events = app.data{app.genoIdx(i)}.events;
                genotypename = app.genos2plot{i};
                behav_mat = matrixconversion(app,events,existing_behavs);

                % Number of pairs to be plotted
                % Correct: for expanded plot
                nflies = size(behav_mat.(app.behavs2plot{1}),1);
                isMultiFile = nflies>1;

                % Convert Time Range to frames
                minFrms = app.timerange(1) * app.fps;
                maxFrms = app.timerange(2) * app.fps;

                % check to see that behav_mat has data in these ranges

                % Check to see if chosen plot style works with data
                %     style_options = {'compact', 'expanded'}; % can pull from GUI
                %
                %     if ~isMultiFile
                %
                %         if ~ismember(style, {'compact','expanded'})
                %             f = msgbox("")
                %         end
                %     else
                %         style = lower(style);
                %     end

                % get stimulation times
                if app.plotstim==1
                    stim_mat = matrixconversion(app,events,app.stim_name);
                    stim = stim_mat.stimulus_on;
                    stim = sum(stim,1);
                    bw = bwconncomp(stim);

                    stim_times = zeros(length(bw.PixelIdxList),2);

                    for s = 1:size(stim_times,1)
                        stim_times(s,:) = [bw.PixelIdxList{s}(1) bw.PixelIdxList{s}(end)];
                    end

                    % correct for offset of stimulation
                end


                %% Create a new figure window.
                fig = figure('Position', [100 100 800 800]);
                halfBarHeight = barHeight / 2;
                halfBarSep = barSep / 2;


                %                 if strcmp(style, 'compact')
                %                     % Show the file numbers along the y axis for the compact style.
                %                     if isMultiFile
                %                         shiftedFileCount = npairs + floor((npairs - 1) / fileGroupCount);
                %                         set(gca, 'YLim', [-(stimulusHeight + stimulusSep), shiftedFileCount * (barHeight + barSep) + stimulusSep], ...
                %                             'YTick', halfBarHeight + halfBarSep:barHeight + barSep:npairs * (barHeight + barSep), ...
                %                             'TickDir', 'out', ...
                %                             'YTickLabel', shiftedFileCount:-1:1, ...
                %                             'TickLength', [0.001 0.001]);
                %                         plotHeight = shiftedFileCount * (barHeight + barSep) + stimulusSep;
                %                     else
                %                         set(gca, 'YTick', [], ...
                %                             'YLim', [-(stimulusHeight + stimulusSep), barHeight + stimulusSep]);
                %                         plotHeight = barHeight + stimulusSep;
                %                     end
                %                 else
                %                     % Show the behavior names along the y axis for the expanded or initial bout styles.
                %                     set(gca, 'YLim', [-(stimulusHeight + stimulusSep), length(behaviors) * (barHeight + barSep) + stimulusSep], ...
                %                         'YTick', halfBarHeight + halfBarSep:barHeight + barSep:length(behaviors) * (barHeight + barSep), ...
                %                         'YTickLabel', fieldToBehaviorName(behaviors(length(behaviors):-1:1)), ...
                %                         'TickDir', 'out', ...
                %                         'TickLength', [0.001 0.001]);
                %                     plotHeight = length(behaviors) * (barHeight + barSep) + stimulusSep;
                %                 end
                %                 set(gca, 'Box', 'off', ...
                %                     'DataAspectRatio', [1 1 1], ...
                %                     'Color', 'none', ...
                %                     'XLim', [minTime - timePad - timeOrigin, maxTime + timePad - timeOrigin] * 20, ...
                %                     'XTick', -6000*20:60*20:6000*20, ...
                %                     'XTickLabel', -6000:60:6000, ...
                %                     'Units', 'points')

                % pull data

                % Set x and y limits
                switch style
                    case 'compact'
                        % set ylim to be nflies*barheight + barsep*(nflies+1)
                        plotHeight = (barHeight*nflies) + barSep*(nflies+1);

                    case 'expanded'
                        plotHeight = (barHeight*nbehavs) + barSep*(nbehavs+1);

                end

                set(gca,'Ylim',[0 plotHeight])
                set(gca,'Xlim',[minFrms maxFrms])
                hold on

                xlims = get(gca,'xlim');
                ylims = get(gca,'ylim');

                % x axis labels
                minutes = (app.timerange(2)-app.timerange(1))/60;
                %minutes= maxTime/60;

                if minutes <= 5
                    xticklabels = 0:0.5:minutes;
                elseif minutes <= 10
                    xticklabels = 0:1:minutes;
                elseif minutes <= 30
                    xticklabels = 0:5:minutes;
                elseif minutes <= 60
                    xticklabels = 0:10:minutes;
                else xticklabels = 0:30:minutes;
                end

                % y axis labels depends on style


                % Set axis properties
                xticks = (xticklabels * 60) / frmRate;
                set(gca,'xtick',xticks)
                set(gca,'xticklabel',xticklabels)
                set(gca,'ytick',[])

                ylabel('Pairs ------->','fontsize',12)

                xlabel('Time (min)','fontsize',12)

                % create an object group for lines of the same behavior so
                % a legend can be created
                hg = hggroup;

                % Add stimulation intervals (optional)
                if app.plotstim==1
                    for s = 1:size(stim_times,1)
                        %             h = patch([stimtimes(i,1) stimtimes(i,1) stimtimes(i,2) stimtimes(i,2)],...
                        %                 [ylims(1) ylims(2) ylims(2) ylims(1)],stimcolor,'EdgeColor','none','FaceAlpha',1);
                        stimobj(s) = rectangle('Position',[stim_times(s,1),ylims(1),stim_times(s,2)-stim_times(s,1),ylims(2)],...
                            'FaceColor',app.stimcolor,'EdgeColor','none');
                    end
                end

                set(stimobj,'Parent',hg)
                set(hg,'Displayname','Stimulus')
                %legend(hg)

                % Align data to the stimulus


                % plotting data for compact ethogram


                % Loop through each behavior
                for b = 1:nbehavs
                    y1 = ylims(1)+barSep;
                    y2 = y1+barHeight;

                    % Loop through each fly
                    for f = 1:nflies

                        bw = bwconncomp(behav_mat.(existing_behavs{b})(f,:));

                        % Loop through each instance of behavior
                        for bb = 1:bw.NumObjects
                            x1 = bw.PixelIdxList{bb}(1);
                            x2 = bw.PixelIdxList{bb}(end);
                            hb(b) = rectangle('Position',[x1,y1,x2-x1+1,barHeight],...
                                'FaceColor',colors2plot{b,2},'EdgeColor',colors2plot{b,2},'linewidth',0.2);
                        end % instances

                        % increase y values
                        y1 = y2+barSep;
                        y2 = y1+barHeight;


                    end %flies

                end %behavior
                %hg = hggroup;
                %                 set(hb,'Parent',hg)
                %                 set(hb,'Displayname','')

                legend(hg)
                title(genotypename,'interpreter','none')


                % set ylim again
                %                 if separate
                %                     set(gca,'ylim',[0 c+1]);
                %                 end


            end % genotype


        end % plot_ethogram

        function T = data2table(app)

            % create cell array for temp storage of data
            cols = 4 + numel(app.plotmetrics);
            genos = {};
            behaviors = {};
            fly = [];
            bin = [];
            file_path = {};
            PT = [];
            TT = [];
            Fbouts = [];
            Dbouts = [];


            % specify variables
            Tvars = {'Genotype','Behavior','Fly','Bin',app.plotmetrics,'File_Path'};

            % parse the bins
            if app.BinCheckBox.Value == 0 % plot total time range
                bins = app.timerange * app.fps;
                nbins = size(bins,1);
                bin_names = {'Total'};
                binName_order = {'Total'};
            else
                % bins specified by user
                nbins = size(app.Bins.Data,1);
                bin_names = cell(nbins,1);
                bins = zeros(nbins,2);
                for i = 1:nbins
                    bin_names{i} = app.Bins.Data{i,2};
                    bins(i,1) = app.Bins.Data{i,3};
                    bins(i,2) = app.Bins.Data{i,4};
                end

                %convert bins to frames
                bins = bins * app.fps;

                % fix order of bin_names
                % find first instance of each bin_name and use this for
                % order
                binName_order = unique(bin_names,'stable');
            end

            %'bins' = timebins in which to divide data. MUST BE IN FRAMES!
            %       Format: [start1,end1; start2,end2]

            % Loop through each genotype
            for i = 1:length(app.genos2plot)

                events = app.data{app.genoIdx(i)}.events;
                genotypename = app.genotypes{app.genoIdx(i)};

                % Convert data for genotype to a behavior matrix
                behav_mat = matrixconversion(app,events,app.behavs2plot);

                % Loop through each behavior
                for b = 1:length(app.behavs2plot)
                    behav = app.behavs2plot{b};

                    % Loop through each time bin
                    %for bb = 1:numel(bins)-1
                    for bb = 1:nbins
                        binrange = bins(bb,1)+1:bins(bb,2);

                        % Calculate each metric and add to cell array
                        percent_time = (sum(behav_mat.(behav)(:,binrange),2)/length(behav_mat.(behav)(:,binrange)))*100;

                        total_time = sum(behav_mat.(behav)(:,binrange),2)/app.fps; % tot

                        nflies = size(behav_mat.(behav),1);

                        %bout_frequency & bout_dur
                        freq = []; dur = []; fpath = {};

                        % loop through each fly
                        for f = 1:nflies
                            bouts = bwconncomp(behav_mat.(behav)(f,binrange));
                            nbouts = bouts.NumObjects;
                            freq(f) = nbouts/(length(binrange)/app.fps/60); % nbouts/minute

                            d = zeros(nbouts,1);
                            for ii = 1:nbouts
                                d(ii) = length(bouts.PixelIdxList{ii})/app.fps; % duration in second
                            end
                            dur(f) = mean(d); % mean duration of bouts

                            fpath = [fpath ; events(f).file_path];

                        end % flies loop
                        % put data in temp arrays, these will grow each
                        % loop
                        gtemp = {};
                        gtemp(1:nflies,1) = {genotypename};
                        genos = [genos ; gtemp];

                        btemp = {};
                        btemp(1:nflies,1) = {behav};
                        behaviors = [behaviors ; btemp];

                        ftemp = [];
                        ftemp(1:nflies,1) = 1:nflies;
                        fly = [fly ; ftemp];

                        bintemp = {};
                        bintemp(1:nflies,1) = bin_names(bb); % change to bin_name later
                        bin = [bin ; bintemp];

                        file_path = [file_path ; fpath];

                        PT = [PT ; percent_time];
                        TT = [TT ; total_time];
                        Fbouts = [Fbouts ; freq'];
                        Dbouts = [Dbouts ; dur'];

                    end % behaviors loop

                end  % time bin loop

            end % genotype loop

            % create table

            T = table(categorical(genos),categorical(behaviors),categorical(fly),categorical(bin),PT,TT,Fbouts,Dbouts,file_path);
            T.Properties.VariableNames = {'Genotype','Behavior','Fly','Bin','Percent_time','Total_time','Freq','Dur','File_Path'};
            T.Bin = categorical(T.Bin,binName_order,'Ordinal',true);

        end

        function bxcharts = plotboxchart(app,plotdata)

            set(0,'DefaultTextInterpreter','none'); % set default interpreter so that underscores are written
            set(groot, 'DefaultAxesTickLabelInterpreter', 'none');

            % METRICS TO PLOT
            met2plot = app.plotmetrics;
            nplots = numel(met2plot);

            % GROUP DATA
            grouping = app.plotgrouping;
            groups = unique(plotdata.(grouping));
            nsubplots = numel(groups);
            if contains(grouping,'Behavior')
                subgroup = 'Genotype';
            else subgroup = 'Behavior';
            end

            % COLORS
            behavcols = cellstr(unique(plotdata.Behavior));
            for i = 1:length(behavcols)
                cidx = find(matches(app.behavList(:,1),behavcols{i})==1,1);
                behavcols(i,2) = app.behavList(cidx,2);
            end

            % Y-LIMITS
            Yrange = app.currYrange;

            % MAKE A PLOT FOR EACH GROUP


            % loop through metric
            for m = 1:nplots
                % create figure
                openFigHandles = findall(0, 'type', 'figure');
                numOpenFigures = numel(openFigHandles);

                f(numOpenFigures) = figure('Name',met2plot{m});

                % DATA2EXCEL
                data2write = {}; % raw data for each plot
                stats2write = {}; % stats for each plot
                subplotTitles = {};

                % Determine/verify y-axis limits
                ymin_all = floor(min(plotdata.(met2plot{m})));
                ymax_all = ceil(max(plotdata.(met2plot{m})));


                % loop through groups
                for g = 1:nsubplots

                    % make a subtable of data for group
                    subplot_title = cellstr(groups(g));
                    subplot_title = subplot_title{:};
                    subplotTitles = [subplotTitles subplot_title];
                    idx = plotdata.(grouping)==groups(g);
                    sub = plotdata(idx,:);
                    data2write{g,1} = subplot_title;
                    data2write{g,2} = sub(:,{'Genotype','Behavior','Fly','Bin',met2plot{m},'File_Path'});

                    % sort data by control if specified


                    % create subplots
                    subplot(nsubplots,1,g);
                    metric = met2plot{m};
                    switch grouping
                        case 'Behavior'
                            genos = cellstr(unique(sub.(subgroup)));

                            % plot boxplot
                            switch app.GraphDropDown.Value
                                case 'Box plot'
                                    if ~app.BinCheckBox.Value
                                        h(g) = boxchart(sub.(subgroup),sub.(met2plot{m}));

                                        % pull color of current behavior and apply to all boxes
                                        colidx = find(matches(behavcols(:,1),cellstr(groups(g)))==1);
                                        h(g).BoxFaceColor = behavcols{colidx,2};
                                        h(g).WhiskerLineColor = behavcols{colidx,2};
                                        h(g).MarkerColor = behavcols{colidx,2};

                                        % change face alpha and line width
                                        % for each genotype

                                    else
                                        h = boxchart(sub.Bin,sub.(met2plot{m}),'GroupByColor',sub.(subgroup));
                                        
                                        % pull color of current behavior and apply to all boxes
                                        colidx = find(matches(behavcols(:,1),cellstr(groups(g)))==1);

                                        % set face alpha to different
                                        % levels to differentiate genotypes
                                        if length(app.genos2plot) == 1
                                            Falpha = 0.2;
                                        elseif length(app.genos2plot)>5
                                            Falpha = [0 repmat(0.2,1,length(app.genos2plot)-1)];
                                        else
                                            Falpha = 0:0.2:0.2*(length(app.genos2plot)-1);
                                        end
                                        
                                        for ss = 1:length(unique(sub.(subgroup)))
                                            h(ss).BoxFaceColor = behavcols{colidx,2};
                                            h(ss).WhiskerLineColor = behavcols{colidx,2};
                                            h(ss).MarkerColor = behavcols{colidx,2};
                                            h(ss).BoxFaceAlpha = Falpha(ss);
                                        end
                                        if g==1
                                            legend(genos,'Location','best','Interpreter','none');
                                        end
                                    end

                                    % plot barplot
                                case 'Bar plot'
                                    if ~app.BinCheckBox.Value
                                        % get data and groups

                                        % calculate means and standard errors

                                        % Bar positions

                                        % Plot bars

                                        % Adjust colors

                                        % Add error bars
                                        h(g) = boxchart(sub.(subgroup),sub.(met2plot{m}));
                                        % pull color of current behavior and apply to all boxes
                                        colidx = find(matches(behavcols(:,1),cellstr(groups(g)))==1);
                                        h(g).BoxFaceColor = behavcols{colidx,2};
                                        h(g).WhiskerLineColor = behavcols{colidx,2};
                                        h(g).MarkerColor = behavcols{colidx,2};

                                    end
                            end

                            % get ymin & ymax for metric across all
                            % genotypes
                            ymin = Yrange.Ymin(Yrange.Metric==metric & Yrange.Behavior==subplot_title);
                            ymax = Yrange.Ymax(Yrange.Metric==metric & Yrange.Behavior==subplot_title);
                            if ymax == 0
                                ymax = 1;
                            end
                            ylim([ymin ymax]);
                            ylabel(met2plot{m})
                            title(subplot_title)
                            %display warning that data has been cut off


                        case 'Genotype'
                            % control should first
                            hold on
                            behavs = cellstr(unique(sub.(subgroup))); % each will be it's own figure
                            if ~app.BinCheckBox.Value
                                for ii = 1:length(behavs)
                                    % pull color of all plotted behaviors and apply to each
                                    colidx = find(matches(behavcols(:,1),behavs(ii))==1);
                                    ydata = sub(sub.(subgroup)==behavs{ii},met2plot{m});
                                    ydata = ydata.(met2plot{m});
                                    xdata = repmat(ii,size(ydata,1),1);
                                    h(g) = boxchart(xdata,ydata);
                                    h(g).BoxFaceColor = behavcols{colidx,2};
                                    h(g).WhiskerLineColor = behavcols{colidx,2};
                                    h(g).MarkerColor = behavcols{colidx,2};
                                end
                                xticks(1:length(behavs));
                                xticklabels(behavs');
                            else
                                h = boxchart(sub.Bin,sub.(metric),'GroupByColor',sub.(subgroup));
                                for ii = 1:length(h)
                                    colidx = find(matches(behavcols(:,1),behavs(ii))==1);
                                    h(ii).BoxFaceColor = behavcols{colidx,2};
                                    h(ii).WhiskerLineColor = behavcols{colidx,2};
                                    h(ii).MarkerColor = behavcols{colidx,2};
                                end
                            end

                            % get ymin and ymax for metric across behavs
                            ymin = min(Yrange.Ymin(Yrange.Metric==metric));
                            ymax = max(Yrange.Ymax(Yrange.Metric==metric));
                            ylim([ymin ymax]);

                            ylabel(met2plot{m});
                            title(subplot_title);
                            if g==1
                                legend(behavs,'Location','best','Interpreter','none');
                            end
                    end

                    % run statistics
                    if ~strcmp(app.stats,'None')
                        stats2write{g} = run_stats(app,sub,met2plot{m});
                    else
                        stats2write{g} = {''};
                    end

                end % groups (subplots)

                % export raw numbers for a plot and stats (TODO) to Excel
                if app.ExportdatatoExcelCheckBox.Value
                    t = datetime('now', 'Format','yyyy_MM_dd HH:mm');
                    datestring = datestr(t,'YYmmDDHHMM');

                    Fname = ['Figure_' num2str(f(numOpenFigures).Number) '_' f(numOpenFigures).Name '_' datestring '.xlsx'];

                    if ~isempty(app.xlsSavePath)
                        Fname = fullfile(app.xlsSavePath,Fname);
                    end

                    for w = 1:nsubplots
                        warning('off','MATLAB:xlswrite:AddSheet'); %optional
                        % sheet name
                        % if grouping = Genotype, sheets named by behavior
                        % if grouping = Behavior, sheets named by genotype
                        %writetable(data2write{w,2},Fname,'Sheet',w); % add raw data
                        writetable(data2write{w,2},Fname,'Sheet',subplotTitles{w}); % add raw data
                        writecell(stats2write{w},Fname,'Sheet',w,'Writemode','append'); % add stats
                    end
                end
            end % metrics (figures)


        end

        function reset_GUI(app)
            app.data = [];
            app.GenotypeEditField.Value = '';

            app.FilesListBox.Items = {};

            app.behaviors = [];
            app.BehaviorsListBox.Items = {};

            app.StartEditField.Value = 0;
            app.EndEditField.Value = 0;
            app.timerange = [];
            app.timerangelimit = [];

            app.genotypes = {};
            app.GenotypestoplotList.Items = {};
            app.CommonStart.Value = 0;
            app.CommonEnd.Value = 0;
            app.BehaviorstoplotList.Items = {};

            app.ControlDropDown.Items = {'None'};


        end


        function out = compareParams(app,params)
            % Compare multiple tables and determine which ones are the same or different
            % Inputs:
            % varargin - Variable input arguments, where each argument is a table

            % Ensure that at least two tables are provided
            % if length(params) < 2
            %     error('You must provide at least two tables to compare.');
            % end

            % Check if all inputs are tables
            for i = 1:length(params)
                if ~istable(params{i})
                    error('All inputs must be tables.');
                end
            end

            % error message for output
            errormsg = {};

            % Loop over all pairs of tables
            for i = 1:length(params)-1
                for j = i+1:length(params)
                    table1 = params{i};
                    table2 = params{j};
                    %fprintf('Comparing Table %d and Table %d...\n', i, j);

                    % Check if the tables have the same size
                    [rows1, cols1] = size(table1);
                    [rows2, cols2] = size(table2);

                    if rows1 ~= rows2 || cols1 ~= cols2
                        % TODO: put all this into an error message
                        fprintf('Table %d and Table %d have different dimensions:\n', i, j);
                        fprintf('Table %d: %d rows, %d columns\n', i, rows1, cols1);
                        fprintf('Table %d: %d rows, %d columns\n', j, rows2, cols2);
                        errormsg = [errormsg sprintf('Table %d and Table %d have different dimensions.', i, j)];
                        continue;
                    end

                    % Check if the variable names (column headers) are the same
                    if ~isequal(table1.Properties.VariableNames, table2.Properties.VariableNames)
                        errormsg = [errormsg sprintf('Table %d and Table %d have different column headers.n', i, j)];
                        %disp('Table 1 Variable Names:');
                        %disp(table1.Properties.VariableNames);
                        %disp('Table 2 Variable Names:');
                        %disp(table2.Properties.VariableNames);
                        %fprintf('Table %d and Table %d are different.\n\n', i, j);
                        continue;
                    end

                    % Compare data in the tables element-wise
                    identical = true;
                    for row = 1:rows1
                        for col = 1:cols1
                            val1 = table1{row, col};
                            val2 = table2{row, col};

                            % Use isequal to check for exact equality of the elements
                            if ~isequal(val1, val2)
                                identical = false;
                                break;
                            end
                        end
                        if ~identical
                            break;
                        end
                    end

                    % Report the result of the comparison
                    if ~identical
                        errormsg = [errormsg sprintf('Table %d and Table %d have different data.', i, j)];
                    end
                end
            end

            % if any did not match, alert user
            if isempty(errormsg)
                out = true;
            else
                uialert(app.UIFigure, ['Not all param files match! The following differences were found.' errormsg], 'Mismatching params!');
                out = false;
            end
        end


        function [behaviorClasses, behavTable, behavList] = get_params(app)

            %GET_PARAMS This function imports behaviors and colors for grooming
            %plots from the params.csv file associated with mat files
            %  This function is a modification of import_behavparams


            %% Import csv file and make struct

            T = app.data{1}.params; % use the first params file since all should be identical

            T.BehaviorClass = categorical(T.BehaviorClass);

            % get BehaviorClasses and create structure
            behav_classes = cellstr(unique(T.BehaviorClass));    % behavior classes from 1st col

            % reorder behav_classes as specified in ClassOrder
            T = sortrows(T,{'ClassOrder','BehaviorOrder'},{'ascend','ascend'}); % sort rows by class and behavior

            % order_ind = [];
            % for i = 1:length(behav_classes)
            %     order_ind = [order_ind unique(T{T.BehaviorClass==behav_classes{i},'ClassOrder'})];
            % end
            % [order_ind,idx] = sort(order_ind);
            % behav_classes = behav_classes(idx);


            % % Behavior Classes (this will be imported from a params file in future)
            %     behaviorClasses.head_cleaning = {'head_cleaning', 'antennal_cleaning', 'proboscis_cleaning', 'ventral_head'};
            %     behaviorClasses.front_leg_rubbing = {'front_leg_rubbing', 'b_1st_and_2nd_leg_rub', 'front_proximal_leg_rub'};
            %     behaviorClasses.wing_cleaning = {'wing_cleaning', 'ventral_wing', 'wing_blade', 'wing_hinge_slash_haltere'};
            %     behaviorClasses.back_leg_rubbing = {'back_leg_rubbing', 'proximal_leg_rub', 'b_2nd_and_3rd_leg_rub'};
            %     behaviorClasses.abdominal_cleaning = {'abdominal_cleaning', 'genital_cleaning'};
            %     behaviorClasses.other = {'ventral_front_cleaning', 'grooming_attempt', 'first_leg_notum', 'ventral_back_cleaning', ...
            %                              'back_leg_grooming_attempt', 'standing_still', 'on_back', 'slip_slash_jump', 'ventral_middle'};

            behaviorClasses = struct;
            behavList = {};

            for i = 1:length(behav_classes)
                bIdx =  find(T.BehaviorClass==behav_classes{i}==1);
                if ~isempty(bIdx)
                    % get behaviors for this class
                    behavs = T.Behavior(bIdx);                                               % get behaviors belonging to this class
                    %[~,bind] = sort(T.BehaviorOrder(bIdx));     % necessary?                                 % get and sort behavior order
                    %behavs = behavs(bind);                      % necessary?                                   % reorder behaviors according to specified order
                    %behavs = reshape(behavs,1,size(behavs,1));  % necessary?                            % reshape into 1x... array

                    % get colors for behav class & individual behaviors
                    classcolor = [T.R1(bIdx(1)) T.G1(bIdx(1)) T.B1(bIdx(1))];
                    behavcolors = [T.R2(bIdx) T.G2(bIdx) T.B2(bIdx)];
                    %behavcolors = behavcolors(bind,:);  % sort to match behaviors, necessary?

                    % add to behaviorClasses structure
                    behaviorClasses.(behav_classes{i}).classcolor =  classcolor;
                    behaviorClasses.(behav_classes{i}).behaviors = behavs;
                    behaviorClasses.(behav_classes{i}).behaviorcolors = behavcolors;

                    % build temporary BList
                    blist = [];
                    blist{1,1} = behav_classes{i};
                    blist{1,2} = classcolor;
                    blist(2:1+length(behavs),1) = behavs;
                    for ii = 1:length(behavs)
                        blist{ii+1,2} = behavcolors(ii,:);
                    end

                    behavList = [behavList ; blist];

                end

            end

            % find repeated entries in behavList
            [~,stringIdx] = unique(behavList(:,1)); % behavList
            behavList = behavList(stringIdx,:);


            behavTable = T;

        end

        function [mainTest,posthocs] = run_stats(app,plotdata,metric)


            % Specify comparisons and groups
            comparison = '';
            ngroups = [];
            nbehavs = length(unique(plotdata.Behavior));
            ngenos = length(unique(plotdata.Genotype));
            nbins = length(unique(plotdata.Bin));

            switch app.plotgrouping
                case 'Behavior'
                    % 1 genotype & 1 bin, No comparison
                    if ngenos == 1 & nbins ==1
                        comparison = 'None';
                        statTest = 'None';
                        groups = unique(plotdata.Genotype);
                        %ngroups = length(groups);

                        % multiple genotypes & 1 bin, compare genotypes
                    elseif ngenos > 1 & nbins == 1
                        comparison = 'Genotype';
                        if ngenos > 2
                            statTest = 'anova1';
                        else
                            statTest = 'pairwise';
                        end
                        groups = unique(plotdata.Genotype);
                        %ngroups = length(groups);

                        % 1 genotype and multiple bins, compare bins
                    elseif ngenos == 1 & nbins > 1
                        comparison = 'Bin';
                        if nbins > 2
                            statTest = 'anova1';
                        else
                            statTest = 'pairwise';
                        end
                        groups = unique(plotdata.Bin);
                        %ngroups = length(groups);

                        % multiple genotypes & multiple bins, compare genotypes
                        % by bin
                    else
                        comparison = 'Genotype x Bin';
                        statTest = 'anova2';
                        groups = {unique(plotdata.Genotype),unique(plotdata.Bin)};
                        %ngroups = length(groups);
                    end

                case 'Genotype'
                    % 1 behavior & 1 bin, No comparison
                    if nbehavs == 1 & nbins ==1
                        comparison = 'None';
                        statTest = 'None';
                        groups = unique(plotdata.Behavior);
                        %ngroups = length(groups);

                        % multiple behaviors & 1 bin, compare genotypes
                    elseif nbehavs > 1 & nbins == 1
                        comparison = 'Behavior';
                        if nbehavs > 2
                            statTest = 'anova1';
                        else
                            statTest = 'pairwise';
                        end
                        groups = unique(plotdata.Behavior);
                        %ngroups = length(groups);

                        % 1 behavior and multiple bins, compare bins
                    elseif nbehavs == 1 & nbins > 1
                        comparison = 'Bin';
                        if nbins > 2
                            statTest = 'anova1';
                        else
                            statTest = 'pairwise';
                        end
                        groups = unique(plotdata.Bin);
                        %ngroups = length(groups);

                        % multiple behaviors & multiple bins, compare genotypes
                        % by bin
                    else
                        comparison = 'Behavior x Bin';
                        statTest = 'anova2';
                        %groups =
                        groups = {unique(plotdata.Behavior),unique(plotdata.Bin)};
                        %ngroups = length(groups);
                    end
            end

            % groups = unique(plotdata.(comparison));
            % ngroups = length(groups);

            % define control if selected by user (TODO: fix for other
            % groups)
            conIdx = [];
            if ~strcmp(app.plotcontrol,'None')
                switch comparison
                    case 'Genotype'
                        conIdx = groups==app.plotcontrol;
                    case 'Genotype x Bin'
                        conIdx = groups{1}==app.plotcontrol;
                    case 'Behavior'
                        conIdx = groups==app.plotcontrol;
                    case 'Behavior x Bin'
                        conIdx = groups{1}==app.plotcontrol;
                end
            end


            % cellarray for output of stats to be written to csv
            mainTest = {};
            posthocs = {};

            % If number of groups > 2 Run ANOVA
            switch statTest

                case 'None'
                    % Alert user that they must have 2 things to compare and skip
                    % TODO: add chi-squared test
                    uialert(app.UIFigure, 'You must have more than one group to compare.', 'Insufficient groups!');
                    return

                case 'pairwise' % Run 2 way test
                    group1 = plotdata.(metric)(plotdata.(comparison)==groups(1));
                    group2 = plotdata.(metric)(plotdata.(comparison)==groups(2));

                    switch app.stats % what type of statistics
                        case 'Parametric'
                            % run 2-tailed T-test
                            mainTest(1,:) = {'','',''};

                            if strmatch(comparison,{'Genotype','Behavior'})
                                [~,p] = ttest2(group1,group2);
                                mainTest(2,:) = {'Test','Unpaired T-test (2-tailed)','alpha=0.05'};

                            elseif strcmp(comparison,'Bin')
                                [~,p] = ttest(group1,group2);
                                mainTest(2,:) = {'Test','Paired T-test (2-tailed)','alpha=0.05'};
                            end

                            mainTest(3,:) = {'Comparison',comparison,'Significant'};
                            if p < 0.05
                                signif = '*';
                            else
                                signif = '';
                            end
                            mainTest(4,:) = {'pval',p,signif};

                        case 'Non-parametric'
                            % run 2-tailed Mann-Whitney U-test]
                            mainTest(1,:) = {'','',''};
                            if strmatch(comparison,{'Genotype','Behavior'})
                                [p,h] = ranksum(group1,group2);
                                mainTest(2,:) = {'Test','Mann-Whitney U-test (2-tailed)','alpha=0.05'};
                            elseif strcmp(comparison,'Bin')
                                [p,h] = signrank(group1,group2);
                                mainTest(2,:) = {'Test','Wilcoxon Signed-rank Test','alpha=0.05'};
                            end

                            mainTest(3,:) = {'Comparison',comparison,'Significant'};

                            if p < 0.05
                                signif = '*';
                            else
                                signif = '';
                            end
                            mainTest(4,:) = {'pval',p,signif};
                    end %stat type

                case 'anova1' % if greater than 2 run ANOVA and then post-hoc
                    switch app.stats % what type of statistics
                        case 'Parametric'
                            % run ANOVA
                            p = anova1(plotdata.(metric),plotdata.(comparison),'off');
                            mainTest(1,:) = {'','',''};
                            mainTest(2,:) = {'Test','1-way ANOVA',''};
                            mainTest(3,:) = {'Comparison',comparison,'Significant'};
                            if p < 0.05
                                signif = '*';
                            else
                                signif = '';
                            end
                            mainTest(4,:) = {'pval',p,signif};

                            % run post-hoc pairwise t-tests with Bonferroni
                            % correction (TODO: add paired vs unpaired
                            % option)
                            if strcmp(comparison,'Bin') % perform paired pairwise comparisons
                                posthocs = posthocTest(app,plotdata,metric,comparison,groups,conIdx,true,[]);
                            else % perform unparied tests
                                posthocs = posthocTest(app,plotdata,metric,comparison,groups,conIdx,false,[]);
                            end
                            %mainTest(:,3) = {''};
                            mainTest(5,:) = {''};
                            mainTest = [mainTest ; posthocs];

                        case 'Non-parametric'
                            % run Kruskal-Wallis
                            p = anova1(plotdata.(metric),plotdata.(comparison),'off');
                            mainTest(1,:) = {'','',''};
                            mainTest(2,:) = {'Test','Kruskal-Wallis',''};
                            mainTest(3,:) = {'Comparison',comparison,'Significant'};
                            if p < 0.05
                                signif = '*';
                            else
                                signif = '';
                            end
                            mainTest(4,:) = {'pval',p,signif};

                            % run post-hoc pairwise Mann-Whitney U-tests with Bonferroni correction
                            % run post-hoc pairwise t-tests with Bonferroni correction
                            if strcmp(comparison,'Bin')
                                posthocs = posthocTest(app,plotdata,metric,comparison,groups,conIdx,true,[]);
                            else
                                posthocs = posthocTest(app,plotdata,metric,comparison,groups,conIdx,false,[]);
                            end
                            %mainTest(:,3) = {''};
                            mainTest(5,:) = {''};
                            mainTest = [mainTest ; posthocs];
                    end

                case 'anova2'
                    switch app.stats

                        case 'Parametric'

                            % 2-way ANOVA
                            if strcmp(comparison,'Behavior x Bin')
                                factorname = 'Behavior';
                                ngroups = nbehavs;
                            else
                                factorname = 'Genotype';
                                ngroups = ngenos;
                            end

                            [p, tbl]= anovan(plotdata.(metric), {plotdata.(factorname), plotdata.Bin}, 'model', 'interaction',...
                                'varnames',{factorname,'Bins'},'display','off');
                            mainTest(1,:) = {'','',''};
                            mainTest(2,:) = {'Test','2-way ANOVA',''};
                            mainTest(3,:) = {'Comparison',comparison,'Significant'};
                            
                            signif = {};
                            for i = 1:length(p)
                            if p(i) < 0.05
                                signif{i} = '*';
                            else
                                signif{i} = '';
                            end
                            end
                            mainTest(4,:) = {tbl{2,1},tbl{2,7},''};
                            mainTest(5,:) = {tbl{3,1},tbl{3,7},''};
                            mainTest(6,:) = {tbl{4,1},tbl{4,7},''};
                            mainTest(4:6,3) = signif';
                            mainTest(7,:) = {'','',''};


                            % posthoc tests

                            % family 1 = compare behavior at each bin
                            % calculate number of comparisons for family
                            if strcmp(app.plotcontrol,'None')
                                ncomps = nchoosek(ngroups,2) * nbins;
                                adj_alpha = 0.05/ncomps;
                            else
                                ncomps = (ngroups-1) * nbins;
                                adj_alpha = 0.05/ncomps;
                            end

                            % loop through each Bin
                            factorGroups = cellstr(groups{2});

                            for i = 1:nbins
                                sub = plotdata(plotdata.Bin==factorGroups(i),:);
                                posthocs = posthocTest(app,sub,metric,factorname,groups{1},conIdx,false,adj_alpha); % add option to suppress header
                                if i == 1
                                    %mainTest(end+1,:) = {''};
                                    mainTest = [mainTest ; posthocs(1:4,:)];
                                    mainTest{10,3} = '';
                                    mainTest = [mainTest ; {'Family1','',['alpha = ' num2str(adj_alpha)]}];
                                    mainTest = [mainTest ; {factorGroups{i},'',''}];
                                    mainTest = [mainTest ; posthocs(5:end,:)];
                                    
                                else
                                    mainTest = [mainTest ; {factorGroups{i},'',''}];
                                    mainTest = [mainTest ; posthocs(5:end,:)];

                                end

                            end


                            % family 2 = compare bins at each behavior
                            % calculate number of comparisons (no
                            % control bin)
                            ncomps = nchoosek(nbins,2) * ngroups;
                            adj_alpha = 0.05/ncomps;

                            % loop through each Behav/Geno
                            factorGroups = cellstr(groups{1});
                            for i = 1:ngroups
                                sub = plotdata(plotdata.(factorname)==factorGroups(i),:);
                                posthocs = posthocTest(app,sub,metric,'Bin',groups{2},[],true,adj_alpha);
                                if i == 1
                                    mainTest = [mainTest; {'','',''}];
                                    mainTest = [mainTest ; {'Family2','',['alpha = ' num2str(adj_alpha)]}];
                                    mainTest = [mainTest ; {factorGroups{i},'',''}];
                                    mainTest = [mainTest ; posthocs(5:end,:)];
                                else
                                    mainTest = [mainTest ; {factorGroups{i},'',''}];
                                    mainTest = [mainTest ; posthocs(5:end,:)];
                                end
                            end


                        case 'Non-parametric'


                            % Warn user that a non-parametric 2 way anova
                            % is not currently implemented
                            mainTest(1,:) = {'','',''};
                            mainTest(2,:) = {'A non-parametric 2 way is not possible at this time.','',''};
                            mainTest(3,:) = {'If data fits normality, use Parametric 2 way ANOVA to test for interaction.','',''};
                          
                    end
            end


        end

        function tbl = posthocTest(app,data,metric,comparison,groups,conIdx,paired,alpha)

            % calculate number of comparisons and adj_alpha
            if isempty(conIdx) % CONDUCT ALL-BY-ALL PAIRWISE COMPARISONS
                ngroups = length(groups);
                ncomps = (ngroups*(ngroups-1)/2);
                tempResults = {};
                if isempty(alpha)
                    adj_alpha = 0.05/ncomps;
                else
                    adj_alpha = alpha;
                end
            else
                % number of comparisons
                ngroups = length(groups);
                ncomps = ngroups-1;
                if ~isempty(alpha)
                    adj_alpha = alpha;
                else
                    adj_alpha = 0.05/ncomps;
                end
            end


            tbl(1,:) = {'Posthoc Test Results','',''};
            switch app.stats
                case 'Parametric'
                    if paired
                        tbl(2,:) = {'Test','Paired T-Test (2 tailed)',''};
                    else
                        tbl(2,:) = {'Test','Unpaired T-Test (2 tailed)',''};
                    end
                case 'Non-parametric'
                    if paired
                        tbl(2,:) = {'Test','Wilcoxon Signed-rank test (2 tailed)',''};
                    else
                        tbl(2,:) = {'Test','Mann-Whitney U-test (2 tailed)',''};
                    end
            end
            tbl(3,:) = {'Correction','Bonferroni',['alpha = ' num2str(adj_alpha)]};
            tbl(4,:) = {'Comparison','pval','Significant'};

            % pvals table
            pvals = {};
            if isempty(conIdx) % CONDUCT ALL-BY-ALL PAIRWISE COMPARISONS

                for a = 1:length(groups)-1
                    for b = a+1:length(groups)
                        group1 = data.(metric)(data.(comparison)==groups(a));
                        group1Name = char(unique(data.(comparison)(data.(comparison)==groups(a))));
                        group2 = data.(metric)(data.(comparison)==groups(b));
                        group2Name = char(unique(data.(comparison)(data.(comparison)==groups(b))));


                        switch app.stats
                            case 'Parametric'
                                if paired
                                    [~,p] = ttest(group1,group2);
                                else
                                    [~,p] = ttest2(group1,group2);
                                end

                                % write results to table
                                if p < adj_alpha
                                    signif = '*';
                                else
                                    signif = '';
                                end
                                tempResults = [tempResults; {[group1Name ' vs ' group2Name],p,signif}];

                            case 'Non-parametric'
                                if paired
                                    [p,h] = signrank(group1,group2);
                                else
                                    [p,h] = ranksum(group1,group2);
                                end
                                if p < adj_alpha
                                    signif = '*';
                                else
                                    signif = '';
                                end
                                tempResults = [tempResults; {[group1Name ' vs ' group2Name],p,signif}];
                        end
                    end
                end
                %tbl{4,3} = ['adjusted alpha = ' num2str(adj_alpha)];
                tbl = [tbl ; tempResults];

            else % CONDUCT PAIRWISE COMPARISONS TO CONTROL

                %create index for control and others
                con = find(conIdx==1);
                comp = find(conIdx==0); % non-controls

                tempResults = {};

                for a = 1:length(comp)

                    group1 = data.(metric)(data.(comparison)==groups(con));
                    group1Name = char(unique(data.(comparison)(data.(comparison)==groups(con))));
                    group2 = data.(metric)(data.(comparison)==groups(comp(a)));
                    group2Name = char(unique(data.(comparison)(data.(comparison)==groups(comp(a)))));


                    switch app.stats
                        case 'Parametric'
                            if paired
                                [~,p] = ttest(group1,group2);
                            else
                                [~,p] = ttest2(group1,group2);
                            end
                            % write results to table
                            if p < adj_alpha
                                signif = '*';
                            else
                                signif = '';
                            end
                            tempResults = [tempResults; {[group1Name ' vs ' group2Name],p,signif}];

                        case 'Non-parametric'
                            if paired
                                [p,h] = signrank(group1,group2);
                            else
                                [p,h] = ranksum(group1,group2);
                            end
                            if p < adj_alpha
                                signif = '*';
                            else
                                signif = '';
                            end
                            tempResults = [tempResults; {[group1Name ' vs ' group2Name],p,signif}];
                    end
                end
                %pvals{4,3} = ['adjusted alpha = ' num2str(adj_alpha)];
                tbl = [tbl ; tempResults];
            end
        end


        function updateYrangeVals(app,reset)
            % This function pulls calculates the y-min and y-max for metric of each
            % behavior selected to plot and creates/updates a table with
            % these values. This table is used to populate the Y-min &
            % Y-max boxes in the GUI and is used for the box and bar plots

            % Input:
            % RESET = boolean. Resets all values to default


            %variables for Yrange table
            metrics = {'Freq','Dur','Percent_time','Total_time'};
            behavs = {};
            mets = {};

            plotdata = data2table(app); % table of data (includes only behavs2plot)

            % 1) if no table exists for app.dataYrange, create one
            if isempty(app.dataYrange) || reset

                for i = 1:length(app.behavs2plot)
                    behavs = [behavs; repmat(app.behavs2plot(i),[4 1])];
                    mets = [mets ; metrics'];
                end

                % create table to store yrange data
                Yrange = table(behavs,mets,zeros(length(behavs),1),zeros(length(behavs),1),...
                    'VariableNames',{'Behavior','Metric','Ymin','Ymax'});
                % make Behavior and metric categorical arrays for easier indexing
                Yrange.Behavior = categorical(Yrange.Behavior);
                Yrange.Metric = categorical(Yrange.Metric);

                %autopopulate with behavs2plot
                for i=1:length(app.behavs2plot) % loop through behaviors
                    yvals = {}; % rows: Freq, Dur, Percent, Total, cols: Ymin Ymax
                    behavior = app.behavs2plot{i};
                    sub = plotdata(plotdata.Behavior==behavior,:); % restrict to one behavior
                    S = summary(sub); % summary of sub data

                    for ii = 1:length(metrics)
                        Yrange.Ymin(Yrange.Behavior==behavior & Yrange.Metric==metrics{ii}) = ...
                            floor(S.(metrics{ii}).Min);
                        Yrange.Ymax(Yrange.Behavior==behavior & Yrange.Metric==metrics{ii}) = ...
                            ceil(S.(metrics{ii}).Max);
                    end %metrics loop
                end %behavs2plot loop

                % create table to store current data used for GUI
                app.dataYrange = Yrange;
                app.currYrange = Yrange;


                % 2) if table already exists
            else
                existbehavs = cellstr(unique(app.dataYrange.Behavior)); % get list of behaviors in table

                % remove any behaviors that are no longer selected
                behav2remove = setdiff(existbehavs,app.behavs2plot);
                if ~isempty(behav2remove)
                    for i = 1:length(behav2remove)
                        removeInd = app.dataYrange.Behavior~=behav2remove{i};
                        app.dataYrange = app.dataYrange(removeInd,:);
                        app.currYrange = app.currYrange(removeInd,:);
                    end
                end

                % add any behaviors not in the table
                behav2add = setdiff(app.behavs2plot,existbehavs);
                if ~isempty(behav2add)

                    % populate values for new behaviors, but leave exisiting ones alone
                    for i=1:length(behav2add)
                        yvals = {}; % rows: Freq, Dur, Percent, Total, cols: Ymin Ymax
                        behavior = behav2add{i};
                        sub = plotdata(plotdata.Behavior==behavior,:); % restrict to one behavior
                        S = summary(sub); % summary of behavior table

                        for ii = 1:length(metrics)
                            app.dataYrange(end+1,:) = {categorical({behavior}),categorical(metrics(ii)),...
                                floor(S.(metrics{ii}).Min),ceil(S.(metrics{ii}).Max)};
                            app.currYrange(end+1,:) = {categorical({behavior}),categorical(metrics(ii)),...
                                floor(S.(metrics{ii}).Min),ceil(S.(metrics{ii}).Max)};
                        end % metrics loop
                    end % behav2add loop
                end % behav2add if

            end % if isempty(app.Yrange)

            % replace NaNs with 0
            idx = isnan(app.dataYrange.Ymin);
            app.dataYrange.Ymin(idx) = 0;
            app.currYrange.Ymin(idx) = 0;
            idx = isnan(app.dataYrange.Ymax);
            app.dataYrange.Ymax(idx) = 0;
            app.currYrange.Ymax(idx) = 0;

        end % updateYrangeVals function


        function results = populate_Yranges(app)

            % currently selected behavior
            currBehav = app.SelectBehaviorDropDown.Value;

            % vars
            tableMetric = categorical({'Freq','Dur','Percent_time','Total_time'});
            GUIMetric = {'freq','dur','per','tot'};

            % % change bold and reset button color if different from default values
            % diffIndex = compareYranges(app,currBehav);
            for i = 1:length(tableMetric)
                idx = find(app.currYrange.Behavior==currBehav & app.currYrange.Metric==tableMetric(i));

                %Ymin
                name = ['Ymin_' GUIMetric{i}];
                buttonName = ['Reset_' GUIMetric{i}];
                app.(name).Value = app.currYrange.Ymin(idx);
                if app.currYrange.Ymin(idx) ~= app.dataYrange.Ymin(idx)
                    % bold value in GUI
                    app.(name).FontWeight =  'bold';
                    app.(name).BackgroundColor = [0.95 0.85 0.85];

                    % make RESET button RED
                    app.(buttonName).BackgroundColor = [0.95 0.85 0.85];
                else
                    % unbold value in GUI
                    app.(name).FontWeight =  'normal';
                    app.(name).BackgroundColor = [1 1 1];

                    % make RESET button RED
                    app.(buttonName).BackgroundColor = [1 1 1];
                end

                %Ymax
                name = ['Ymax_' GUIMetric{i}];
                app.(name).Value = app.currYrange.Ymax(idx);
                if app.currYrange.Ymax(idx) ~= app.dataYrange.Ymax(idx)
                    % bold value in GUI
                    app.(name).FontWeight =  'bold';
                    app.(name).BackgroundColor = [0.95 0.85 0.85];

                    % make RESET button RED
                    app.(buttonName).BackgroundColor = [0.95 0.85 0.85];
                else
                    % unbold value in GUI
                    app.(name).FontWeight =  'normal';
                    app.(name).BackgroundColor = [1 1 1];

                    % make RESET button RED
                    app.(buttonName).BackgroundColor = [1 1 1];
                end
            end
        end


        function emptyCells = findEmptyCells(app,table1)
            % Initialize logical table for empty cells
            emptyCells = table();

            % Iterate over each variable (column)
            for i = 1:width(table1)
                var = table1.(i);

                % Detect empty values based on data type
                if iscell(var)
                    emptyCells.(table1.Properties.VariableNames{i}) = cellfun(@isempty, var);
                elseif isnumeric(var) || islogical(var)
                    emptyCells.(table1.Properties.VariableNames{i}) = isnan(var);
                elseif iscategorical(var)
                    emptyCells.(table1.Properties.VariableNames{i}) = isundefined(var);
                else
                    error('Unsupported data type in table.');
                end
            end

        end
    end % end of private functions

    methods (Access = public)

        function behaviors = getcommonbehaviors(app)

            behaviors = fieldnames(app.data{1}.events);
            for i = 2:length(app.data)
                behaviors = intersect(behaviors, fieldnames(app.data{i}.events));
            end

            if ~isempty(ismember({'file_path' app.stim_name},behaviors))
                keepIdx = ones(1,length(behaviors));
                keepIdx(strcmp('file_path',behaviors)) = 0;
                keepIdx(strcmp('stimulus_on',behaviors)) = 0;
                keepIdx = logical(keepIdx);
                behaviors = behaviors(keepIdx);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.matIdx = 1;
            app.fps = 30;
            app.win = 1;
        end

        % Button pushed function: AddfilesButton
        function AddfilesButtonPushed(app, event)
            % Prompt the user for the file(s) to use.
            [fileNames, dirName] = uigetfile({'*.mat','Compiled grooming files (*.mat)'}, 'Pick one or more grooming files', 'MultiSelect', 'on');
            if isscalar(fileNames)
                return % the user cancelled
            end
            if iscell(fileNames)
                filePaths = {};
                for fileName = fileNames
                    filePaths = [filePaths fullfile(dirName, char(fileName))]; %#ok<AGROW>
                end
                filePaths = sort(filePaths);
            else
                filePaths = {fullfile(dirName, fileNames)};
                fileNames = {fileNames};
            end

            % Don't add mat files that are already in the list
            fileNames2remove = ones(1,length(fileNames));
            if ~isempty(app.FilesListBox.Items)
                for i = 1:length(fileNames) % check if each file name exists
                    if ismember(fileNames{i},app.FilesListBox.Items)
                        fileNames2remove(i) = 0;
                    end
                end
                fileNames2remove = logical(fileNames2remove);
                fileNames = fileNames(fileNames2remove);
            end

            % load mat files and store in ItemData
            ItemData = {};
            genotypes = {};
            params = {};

            for i = 1:length(filePaths)
                ItemData{i} = load(filePaths{i});
                genotypes{i} = ItemData{i}.genotype;
                params{i} = ItemData{i}.params;
            end

            % check to make sure that params match between all mat files.
            % if not all match, alter user and abort adding files

            % Only compare if there are more than two files
            if isempty(app.data) && length(params)==1

                app.data = [app.data ItemData]; % update data
                app.FilesListBox.Items = [app.FilesListBox.Items  fileNames]; % add to list of filenames
                app.GenotypestoplotList.Items = [app.GenotypestoplotList.Items genotypes]; % add to list of genotypes

                update_matfile_info(app); % update fields pertaining to mat file

                % update timerange
                common_timerange = zeros(length(app.data),2);
                for i = 1:length(app.data)
                    genos{i} = app.data{i}.genotype;
                    common_timerange(i,:) = app.data{i}.timeRange;
                end
                app.timerangelimit = [min(common_timerange(:,1)) min(common_timerange(:,2))];
                app.timerange(1) = app.timerangelimit(1);
                app.timerange(2) = app.timerangelimit(2);

                % create table for bins


                % update other common fields
                updatecommonfeats(app);

            else % if more than two things to compare
                % get existing params from GUI
                params2compare = cell(1,length(app.data));
                for i = 1:length(app.data)
                    params2compare{i} = app.data{i}.params;
                end

                % add params from selected files
                params2compare = [params2compare params];

                allmatch = compareParams(app,params2compare); %allmatch will be true if all match

                if allmatch

                    app.data = [app.data ItemData]; % update data
                    app.FilesListBox.Items = [app.FilesListBox.Items  fileNames]; % add to list of filenames
                    app.GenotypestoplotList.Items = [app.GenotypestoplotList.Items genotypes]; % add to list of genotypes

                    update_matfile_info(app); % update fields pertaining to mat file

                    % update timerange
                    common_timerange = zeros(length(app.data),2);
                    for i = 1:length(app.data)
                        genos{i} = app.data{i}.genotype;
                        common_timerange(i,:) = app.data{i}.timeRange;
                    end
                    app.timerangelimit = [min(common_timerange(:,1)) min(common_timerange(:,2))];
                    app.timerange(1) = app.timerangelimit(1);
                    app.timerange(2) = app.timerangelimit(2);

                    % update other common fields
                    updatecommonfeats(app);

                    %             behaviors = getcommonbehaviors(app); % get common behaviors from all files
                    %             app.BehaviorstoplotList.Items = behaviors; % update behaviors to plot
                    %            app.ControlDropDown.Items = [app.ControlDropDown.Items app.GenotypestoplotList.Items]; % update Control dropdown
                end
            end
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)

            value = app.FilesListBox.Value;
            app.matIdx = find(strcmp(event.Source.Value,app.FilesListBox.Items)==1); % Update index of which file is selected
            update_matfile_info(app);

        end

        % Value changed function: GenotypeEditField
        function GenotypeEditFieldValueChanged(app, event)
            value = app.GenotypeEditField.Value;
            app.data{app.matIdx}.genotype = value;
            updatecommonfeats(app);
            %app.GenotypestoplotList.Items{app.matIdx} = value;
        end

        % Button pushed function: RemovefilesButton
        function RemovefilesButtonPushed(app, event)
            % Get names of files to be removed
            app.matIdx

            % Remove names from list (how to deal with only one file?)

            if length(app.matIdx)==length(app.data)
                reset_GUI(app);
            else
                % create index of files to eliminate
                keepers = ones(1,length(app.data));
                keepers(app.matIdx) = 0;
                keepers = logical(keepers);

                % Remove file data from ItemData

                app.data = app.data(keepers); % update data
                app.FilesListBox.Items = app.FilesListBox.Items(keepers); % update list of filenames
                app.GenotypestoplotList.Items = app.GenotypestoplotList.Items(keepers); % update list of genotypes

                update_matfile_info(app); % update fields pertaining to mat file

                % update timerange
                common_timerange = zeros(length(app.data),2);
                for i = 1:length(app.data)
                    genos{i} = app.data{i}.genotype;
                    common_timerange(i,:) = app.data{i}.timeRange;
                end
                app.timerangelimit = [min(common_timerange(:,1)) min(common_timerange(:,2))];
                app.timerange(1) = app.timerangelimit(1);
                app.timerange(2) = app.timerangelimit(2);

                % update other common fields
                updatecommonfeats(app);

            end


        end

        % Value changed function: CommonStart
        function CommonStartValueChanged(app, event)
            value = event.Value;

            % Value can't be less than Start or greater than End
            if value < app.timerangelimit(1)
                % error message that value can't be less than common start
                app.CommonStart.Value = app.timerange(1);
            elseif value >= app.timerangelimit(2)
                % error message that value can't be equal to or greater
                % than common end
                app.CommonStart.Value = app.timerange(1);
            else
                app.timerange(1) = value;
            end
            updatecommonfeats(app)
        end

        % Value changed function: CommonEnd
        function CommonEndValueChanged(app, event)
            value = event.Value;

            % Value can't be less than Start or greater than End
            if value <= app.timerangelimit(1)
                % error message that value can't be less than common start
                app.CommonEnd.Value = app.timerange(2);
            elseif value > app.timerangelimit(2)
                % error message that value can't be equal to or greater
                % than common end
                app.CommonEnd.Value = app.timerange(2);
            else
                app.timerange(2) = value;
            end
            updatecommonfeats(app)
        end

        % Value changed function: GroupingDropDown
        function GroupingDropDownValueChanged(app, event)
            value = app.GroupingDropDown.Value;
            app.plotgrouping = value;
            % update app.GraphDropDown.Items
            switch app.plotgrouping
                % if Behavior, set to genos2plot
                case 'Behavior'
                    % if genos2plot < 2, set to 'None'
                    if length(app.genos2plot) < 2
                        app.ControlDropDown.Items = {'None'};
                        app.ControlDropDown.Value = 'None';
                        app.plotcontrol = 'None';
                    else
                        app.ControlDropDown.Items = ['None' app.genos2plot];
                        app.ControlDropDown.Value = 'None';
                        app.plotcontrol = 'None';
                    end
                    % if Genotype, set to behav2plot
                case 'Genotype'
                    % if behav2plot < 2, set to 'None'
                    if length(app.behavs2plot) < 2
                        app.ControlDropDown.Items = {'None'};
                        app.ControlDropDown.Value = 'None';
                        app.plotcontrol = 'None';
                    else
                        app.ControlDropDown.Items = ['None' app.behavs2plot];
                        app.ControlDropDown.Value = 'None';
                        app.plotcontrol = 'None';
                    end
            end
        end

        % Value changed function: ControlDropDown
        function ControlDropDownValueChanged(app, event)
            value = app.ControlDropDown.Value;
            app.plotcontrol = value;
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            value = app.GraphDropDown.Value;
            app.graphtype = value;
        end

        % Value changed function: StatisticsDropDown
        function StatisticsDropDownValueChanged(app, event)
            value = app.StatisticsDropDown.Value;
            app.stats = value;
            if ~strcmp(value,'None')
                app.ExportdatatoExcelCheckBox.Value = true;
            end
        end

        % Value changed function: ExportdatatoExcelCheckBox
        function ExportdatatoExcelCheckBoxValueChanged(app, event)
            value = app.ExportdatatoExcelCheckBox.Value;
            if value == 1
                app.export2excel = value;
                app.xlsSavePath = cd;
                app.SavePathEditField.Value = app.xlsSavePath;
            elseif value == 0 & ~strcmp(app.StatisticsDropDown.Value,'None')
                app.StatisticsDropDown.Value = 'None';
                app.stats = 0;
                app.export2excel = value;
            end
        end

        % Button pushed function: PlotBox
        function PlotBoxPushed(app, event)
            % This will produce 1 plot per metric chosen

            % check that bins are valid

            plotdata = data2table(app);

            % If control is specified, sort table so that control is first
            % sort genos2plot so control is first
            if contains(app.plotcontrol,'None')
                con = 'None';
                genotypeOrder = app.genos2plot;
                behavOrder = app.behavs2plot;
            else

                switch app.plotgrouping
                    % if Grouping = Behavior, sort genotypes
                    case 'Behavior'
                        con = app.plotcontrol;
                        % reorder genotypes with control first
                        % TODO - This ordering doesn't seem to work.
                        conind = find(contains(app.genos2plot,con)==1);
                        sort_order = [conind setdiff(1:length(app.genos2plot),conind)];
                        %genotypeOrder = categorical(genos2plot(sort_order));
                        desiredOrder = app.genos2plot(sort_order);
                        % Create an index vector for the custom order
                        % [~,idx] = ismember(plotdata.Genotype, genotypeOrder);
                        % [~,idx] = sort(idx);
                        % plotdata = plotdata(idx,:);
                        plotdata.Genotype = categorical(plotdata.Genotype,desiredOrder,'Ordinal',true);

                        % if Grouping = Genotype, sort behaviors
                    case 'Genotype'
                        % reorder genotypes with control first
                        con = app.plotcontrol;
                        conind = find(contains(app.behavs2plot,con)==1);
                        sort_order = [conind setdiff(1:length(app.behavs2plot),conind)];
                        %genotypeOrder = categorical(genos2plot(sort_order));
                        desiredOrder = app.behavs2plot(sort_order);
                        % Create an index vector for the custom order
                        % [~,idx] = ismember(plotdata.Genotype, genotypeOrder);
                        % [~,idx] = sort(idx);
                        % plotdata = plotdata(idx,:);
                        plotdata.Behavior = categorical(plotdata.Behavior,desiredOrder,'Ordinal',true);
                end
            end


            plotboxchart(app,plotdata);


        end

        % Value changed function: StimREditField
        function StimREditFieldValueChanged(app, event)
            value = app.StimREditField.Value;
            app.stimcolor(1) = value;
            app.StimColorLamp.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Value changed function: StimGEditField
        function StimGEditFieldValueChanged(app, event)
            value = app.StimGEditField.Value;
            app.stimcolor(2) = value;
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Value changed function: StimBEditField
        function StimBEditFieldValueChanged(app, event)
            value = app.StimBEditField.Value;
            app.stimcolor(3) = value;
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Value changed function: PlotStimulusCheckBox
        function PlotStimulusCheckBoxValueChanged(app, event)
            value = app.PlotStimulusCheckBox.Value;
            app.plotstim = value;
            if value ==1
                app.stimtimes.Enable = 'on';

                % populate stimtimes
                stim_mat = matrixconversion(app,app.data{1}.events,app.stim_name);
                stim = stim_mat.(app.stim_name);

                stim = sum(stim,1);
                %stim = sum(stim_corr,1);
                bw = bwconncomp(stim);

                stim_times = zeros(length(bw.PixelIdxList),2);

                for s = 1:size(stim_times,1)
                    stim_times(s,:) = [bw.PixelIdxList{s}(1) bw.PixelIdxList{s}(end)];
                end

                % convert to start times and duration in seconds & add to
                % app.stimtimes_all
                stim_starts = round(stim_times(:,1)/app.fps);
                stim_ends = round(stim_times(:,2)/app.fps);
                stim_dur = round((stim_times(:,2)-stim_times(:,1))/app.fps);
                stimTable = table(stim_starts,stim_ends,stim_dur,'VariableNames',{'Start','End','Duration'});
                app.stimtimes_all = stimTable;

                % populate GUI
                app.stimtimes.Data = app.stimtimes_all;

            else
                app.stimtimes.Enable = 'off';
            end
            %             for i = 1:length(app)
            %             stim_mat = matrixconversion_test_new(app);
            %             end

        end

        % Cell edit callback: stimtimes
        function stimtimesCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: PlotHistogram
        function PlotHistogramButtonPushed(app, event)

            plothistogram(app)

        end

        % Value changed function: GenotypestoplotList
        function GenotypestoplotListHistValueChanged(app, event)
            value = event.Value;
            app.genos2plot = value;
            app.genoIdx = find(~cellfun(@isempty,cellfun(@(x)...
                find(strcmp(x,app.genos2plot)>0),app.genotypes,'uniformoutput',false)) == 1); % Update index of which genotype is selected

            %update Control selection for Boxplots
            if strcmp(app.plotgrouping,'Behavior')
                if length(app.genos2plot)<2
                    app.ControlDropDown.Items = {'None'};
                    app.ControlDropDown.Value = 'None';
                    app.plotcontrol = 'None';
                else
                    app.ControlDropDown.Items = ['None' app.genos2plot];
                    app.ControlDropDown.Value = 'None';
                    app.plotcontrol = 'None';
                end
            end

            %update y-ranges
            if ~isempty(app.genos2plot) & ~isempty(app.behavs2plot)

                % update Yrange data
                updateYrangeVals(app,0);

                % populate values for first behavior shown
                populate_Yranges(app);


            else
                app.SelectBehaviorDropDown.Items = {'Option1'};
                app.dataYrange = [];

            end

            % %update Control selection for Histogram (genotype only)
            % if length(app.genos2plot)<2
            %     app.ControlDropDown_Hist.Items = {'None'};
            %     app.ControlDropDown_Hist.Value = 'None';
            % else
            %     app.ControlDropDown_Hist.Items = ['None' app.genos2plot];
            %     app.ControlDropDown_Hist.Value = 'None';
            % end

        end

        % Value changed function: BehaviorstoplotList
        function BehaviorstoplotListHistValueChanged(app, event)
            value = event.Value;
            app.behavs2plot = value;
            app.behavIdx = find(~cellfun(@isempty,cellfun(@(x)...
                find(strcmp(x,app.behavs2plot)>0),app.behaviors,'uniformoutput',false)) == 1);

            %update Control selection
            if strcmp(app.plotgrouping,'Genotype')
                if length(app.behavs2plot)<2
                    app.ControlDropDown.Items = {'None'};
                    app.ControlDropDown.Value = 'None';
                    app.plotcontrol = 'None';
                else
                    app.ControlDropDown.Items = ['None' app.behavs2plot];
                    app.ControlDropDown.Value = 'None';
                    app.plotcontrol = 'None';
                end
            end

            %update Behavior selection list in Metrics
            if ~isempty(app.genos2plot) & ~isempty(app.behavs2plot)
                app.SelectBehaviorDropDown.Items = app.behavs2plot;

                % update Yrange data
                updateYrangeVals(app,0);

                % populate values for first behavior shown
                populate_Yranges(app);

            else
                app.SelectBehaviorDropDown.Items = {'Option1'};
                app.dataYrange = [];

            end

        end

        % Button pushed function: PlotEthogram
        function PlotEthogramButtonPushed(app, event)
            plot_ethogram(app)
        end

        % Value changed function: EthogramtypeDropDown
        function EthogramtypeDropDownValueChanged(app, event)
            value = app.EthogramtypeDropDown.Value;
            app.ethogram_type = value;
        end

        % Value changed function: StimREditField_etho
        function StimREditField_ethoValueChanged(app, event)
            value = app.StimREditField_etho.Value;
            app.stimcolor(1) = value;
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Value changed function: StimBEditField_etho
        function StimBEditField_ethoValueChanged(app, event)
            value = app.StimBEditField_etho.Value;
            app.stimcolor(3) = value;
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Value changed function: StimGEditField_etho
        function StimGEditField_ethoValueChanged(app, event)
            value = app.StimGEditField_etho.Value;
            app.stimcolor(2) = value;
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Drop down opening function: EthogramtypeDropDown
        function EthogramtypeDropDownOpening(app, event)
            app.ethogram_type = 'Compact';
        end

        % Value changed function: PlotStimulusCheckBox_etho
        function PlotStimulusCheckBox_ethoValueChanged(app, event)
            value = app.PlotStimulusCheckBox_etho.Value;
            app.plotstim = value;
            if value ==1
                app.stimtimes.Enable = 'on';
            else
                app.stimtimes.Enable = 'off';
            end

        end

        % Value changed function: BoutFrequencyCheckBox
        function BoutFrequencyCheckBoxValueChanged(app, event)
            value = app.BoutFrequencyCheckBox.Value;

            if value==1 % add to metrics
                if isempty(app.plotmetrics)
                    app.plotmetrics = {'Freq'};
                else
                    app.plotmetrics = [app.plotmetrics 'Freq'];
                end

                % ENABLE RANGE AND AUTO
                app.Ymin_freq.Enable = "on";
                app.Ymax_freq.Enable = "on";

            elseif value==0 % remove from metrics
                ind = contains(app.plotmetrics,'Freq');
                app.plotmetrics = app.plotmetrics(~ind);

                % DISABLE RANGE AND AUTO
                app.Ymin_freq.Enable = "off";
                app.Ymax_freq.Enable = "off";

            end
        end

        % Value changed function: BoutDurationCheckBox
        function BoutDurationCheckBoxValueChanged(app, event)
            value = app.BoutDurationCheckBox.Value;

            if value==1 % add to metrics
                if isempty(app.plotmetrics)
                    app.plotmetrics = {'Dur'};
                else
                    app.plotmetrics = [app.plotmetrics 'Dur'];
                end

                % ENABLE RANGE AND AUTO
                app.Ymin_dur.Enable = "on";
                app.Ymax_dur.Enable = "on";

            elseif value==0 % remove from metrics
                ind = contains(app.plotmetrics,'Dur');
                app.plotmetrics = app.plotmetrics(~ind);

                % DISABLE RANGE AND AUTO
                app.Ymin_dur.Enable = "off";
                app.Ymax_dur.Enable = "off";

            end
        end

        % Value changed function: PercentTimeCheckBox
        function PercentTimeCheckBoxValueChanged(app, event)
            value = app.PercentTimeCheckBox.Value;

            if value==1 % add to metrics
                if isempty(app.plotmetrics)
                    app.plotmetrics = {'Percent_time'};
                else
                    app.plotmetrics = [app.plotmetrics 'Percent_time'];
                end

                % ENABLE RANGE AND AUTO
                app.Ymin_per.Enable = "on";
                app.Ymax_per.Enable = "on";


            elseif value==0 % remove from metrics
                ind = contains(app.plotmetrics,'Percent_time');
                app.plotmetrics = app.plotmetrics(~ind);

                % DISABLE RANGE AND AUTO
                app.Ymin_per.Enable = "off";
                app.Ymax_per.Enable = "off";

            end

        end

        % Value changed function: TotalTimeCheckBox
        function TotalTimeCheckBoxValueChanged(app, event)
            value = app.TotalTimeCheckBox.Value;

            if value==1 % add to metrics
                if isempty(app.plotmetrics)
                    app.plotmetrics = {'Total_time'};
                else
                    app.plotmetrics = [app.plotmetrics 'Total_time'];
                end

                % ENABLE RANGE AND AUTO
                app.Ymin_tot.Enable = "on";
                app.Ymax_tot.Enable = "on";


            elseif value==0 % remove from metrics
                ind = contains(app.plotmetrics,'Total_time');
                app.plotmetrics = app.plotmetrics(~ind);

                % DISABLE RANGE AND AUTO
                app.Ymin_tot.Enable = "off";
                app.Ymax_tot.Enable = "off";

            end


        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            xlspath = uigetdir(cd,'Select where to save the xls file.');
            if isscalar(xlspath)
                return % the user cancelled
            end
            app.xlsSavePath = xlspath;
            app.SavePathEditField.Value = app.xlsSavePath;
        end

        % Button pushed function: ProcesstxtFilesButton
        function ProcesstxtFilesButtonPushed(app, event)
            process_grooming;
        end

        % Value changed function: StartBuffersecEditField
        function StartBuffersecEditFieldValueChanged(app, event)
            value = app.StartBuffersecEditField.Value;

            if ~isnumeric(value)
                % try to convert str to number
                value = double(value);
            end

            app.start_buffer = value;
        end

        % Value changed function: StimTEditField
        function StimTEditFieldValueChanged(app, event)
            value = app.StimTEditField.Value;
            app.stimAlpha = value;
        end

        % Value changed function: Ymin_freq
        function Ymin_freqValueChanged(app, event)
            value = app.Ymin_freq.Value;

            behav = app.SelectBehaviorDropDown.Value;
            idx = find(app.currYrange.Behavior==behav & app.currYrange.Metric=='Freq');

            % if value is greater than Ymin, data will be cut off in graphs
            if value > app.dataYrange{idx,3} % data will be cut off!
                % change value back to default
                app.Ymin_freq.Value = app.currYrange{idx,3};
                %app.dataYrange{idx,3} = Ymin;
            else
                % update app.dataYrange
                app.currYrange{idx,3} = value;
                populate_Yranges(app);
            end


        end

        % Value changed function: Ymax_freq
        function Ymax_freqValueChanged(app, event)
            value = app.Ymax_freq.Value;
            % check to make sure value is greater than min and less than
            % max
            behav = app.SelectBehaviorDropDown.Value;

            %Yrange = app.dataYrange; % get Yrange data (don't need to do this)
            idx = find(app.currYrange.Behavior==behav & app.currYrange.Metric=='Freq');

            % if value is less than Ymax, data will be cut off in graphs
            if value < app.dataYrange{idx,4} % data will be cut off!
                % change value back to default
                app.Ymax_freq.Value = app.currYrange{idx,4};
            else
                % update app.dataYrange
                app.currYrange{idx,4} = value;
                populate_Yranges(app);
            end


        end

        % Value changed function: Ymin_dur
        function Ymin_durValueChanged(app, event)
            value = app.Ymin_dur.Value;

            behav = app.SelectBehaviorDropDown.Value;
            idx = find(app.currYrange.Behavior==behav & app.currYrange.Metric=='Dur');

            % if value is greater than Ymin, data will be cut off in graphs
            if value > app.dataYrange{idx,3} % data will be cut off!
                % change value back to default
                app.Ymin_dur.Value = app.currYrange{idx,3};
                %app.dataYrange{idx,3} = Ymin;
            else
                % update app.dataYrange
                app.currYrange{idx,3} = value;
                populate_Yranges(app);
            end

        end

        % Value changed function: Ymax_dur
        function Ymax_durValueChanged(app, event)
            value = app.Ymax_dur.Value;
            % check to make sure value is greater than min and less than
            % max
            behav = app.SelectBehaviorDropDown.Value;

            %Yrange = app.dataYrange; % get Yrange data (don't need to do this)
            idx = find(app.currYrange.Behavior==behav & app.currYrange.Metric=='Dur');

            % if value is less than Ymax, data will be cut off in graphs
            if value < app.dataYrange{idx,4} % data will be cut off!
                % change value back to default
                app.Ymax_dur.Value = app.currYrange{idx,4};
            else
                % update app.dataYrange
                app.currYrange{idx,4} = value;
                populate_Yranges(app);
            end

        end

        % Value changed function: Ymax_per
        function Ymax_perValueChanged(app, event)
            value = app.Ymax_per.Value;

            % check to make sure value is greater than min and less than
            % max
            behav = app.SelectBehaviorDropDown.Value;

            %Yrange = app.dataYrange; % get Yrange data (don't need to do this)
            idx = find(app.currYrange.Behavior==behav & app.currYrange.Metric=='Percent_time');

            % if value is less than Ymax, data will be cut off in graphs
            if value < app.dataYrange{idx,4} % data will be cut off!
                % change value back to default
                app.Ymax_per.Value = app.currYrange{idx,4};
            else
                % update app.dataYrange
                app.currYrange{idx,4} = value;
                populate_Yranges(app);
            end


        end

        % Value changed function: Ymin_tot
        function Ymin_totValueChanged(app, event)
            value = app.Ymin_tot.Value;

            behav = app.SelectBehaviorDropDown.Value;
            idx = find(app.currYrange.Behavior==behav & app.currYrange.Metric=='Total_time');

            % if value is greater than Ymin, data will be cut off in graphs
            if value > app.dataYrange{idx,3} % data will be cut off!
                % change value back to default
                app.Ymin_tot.Value = app.currYrange{idx,3};
                %app.dataYrange{idx,3} = Ymin;
            else
                % update app.dataYrange
                app.currYrange{idx,3} = value;
                populate_Yranges(app);
            end


        end

        % Value changed function: Ymax_tot
        function Ymax_totValueChanged(app, event)
            value = app.Ymax_tot.Value;

            % check to make sure value is greater than min and less than
            % max
            behav = app.SelectBehaviorDropDown.Value;

            %Yrange = app.dataYrange; % get Yrange data (don't need to do this)
            idx = find(app.currYrange.Behavior==behav & app.currYrange.Metric=='Total_time');

            % if value is less than Ymax, data will be cut off in graphs
            if value < app.dataYrange{idx,4} % data will be cut off!
                % change value back to default
                app.Ymax_tot.Value = app.currYrange{idx,4};
            else
                % update app.dataYrange
                app.currYrange{idx,4} = value;
                populate_Yranges(app);
            end


        end

        % Value changed function: SelectBehaviorDropDown
        function SelectBehaviorDropDownValueChanged(app, event)
            value = app.SelectBehaviorDropDown.Value;


            % populate values for first behavior shown
            populate_Yranges(app);

            % idx = find(app.dataYrange.Behavior==value);
            % sub = app.dataYrange(idx,:);
            %
            % app.Ymin_freq.Value = sub.Ymin(sub.Metric=='Freq');
            % app.Ymax_freq.Value = sub.Ymax(sub.Metric=='Freq');
            % app.Ymin_dur.Value = sub.Ymin(sub.Metric=='Dur');
            % app.Ymax_dur.Value = sub.Ymax(sub.Metric=='Dur');
            % app.Ymin_per.Value = 0;
            % app.Ymax_per.Value = sub.Ymax(sub.Metric=='Percent_time');
            % app.Ymin_tot.Value = sub.Ymin(sub.Metric=='Total_time');
            % app.Ymax_tot.Value = sub.Ymax(sub.Metric=='Total_time');
        end

        % Value changed function: Ymin_per
        function Ymin_perValueChanged(app, event)
            value = app.Ymin_per.Value;

            behav = app.SelectBehaviorDropDown.Value;
            idx = find(app.currYrange.Behavior==behav & app.currYrange.Metric=='Percent_time');

            % if value is greater than Ymin, data will be cut off in graphs
            if value > app.dataYrange{idx,3} % data will be cut off!
                % change value back to default
                app.Ymin_per.Value = app.currYrange{idx,3};

            else
                % update app.dataYrange
                app.currYrange{idx,3} = value;
                populate_Yranges(app);
            end

        end

        % Button pushed function: Reset_freq
        function Reset_freqButtonPushed2(app, event)
            %plotdata = data2table(app); % get current data
            currBehav = app.SelectBehaviorDropDown.Value; % get current behavior
            idx = find(app.dataYrange.Behavior==currBehav & app.dataYrange.Metric=='Freq');

            app.currYrange.Ymin(idx) = app.dataYrange.Ymin(idx);
            app.currYrange.Ymax(idx) = app.dataYrange.Ymax(idx);

            populate_Yranges(app);

            % reset color of button
            %app.Reset_freq.BackgroundColor = [1 1 1];
        end

        % Button pushed function: Reset_dur
        function Reset_durButtonPushed2(app, event)
            plotdata = data2table(app);
            currBehav = app.SelectBehaviorDropDown.Value; % get current behavior
            app.Ymin_dur.Value = floor(min(plotdata.Dur(plotdata.Behavior==currBehav)));
            app.Ymax_dur.Value = ceil(max(plotdata.Dur(plotdata.Behavior==currBehav)));

            Yrange = app.dataYrange; % pull Yrange data
            idx = find(Yrange.Behavior==currBehav & Yrange.Metric=='Dur');
            app.dataYrange{idx,3} = app.Ymin_dur.Value;
            app.dataYrange{idx,4} = app.Ymax_dur.Value;
        end

        % Button pushed function: Reset_per
        function Reset_perButtonPushed2(app, event)
            plotdata = data2table(app);
            currBehav = app.SelectBehaviorDropDown.Value; % get current behavior
            app.Ymin_per.Value = 0;
            app.Ymax_per.Value = ceil(max(plotdata.Percent_time(plotdata.Behavior==currBehav)));

            Yrange = app.dataYrange; % pull Yrange data
            idx = find(Yrange.Behavior==currBehav & Yrange.Metric=='Percent_time');
            app.dataYrange{idx,3} = 0;
            app.dataYrange{idx,4} = app.Ymax_per.Value;
        end

        % Button pushed function: Reset_tot
        function Reset_totButtonPushed2(app, event)
            plotdata = data2table(app);
            currBehav = app.SelectBehaviorDropDown.Value; % get current behavior
            app.Ymin_tot.Value = floor(min(plotdata.Total_time(plotdata.Behavior==currBehav)));
            app.Ymax_tot.Value = ceil(max(plotdata.Total_time(plotdata.Behavior==currBehav)));

            Yrange = app.dataYrange; % pull Yrange data
            idx = find(Yrange.Behavior==currBehav & Yrange.Metric=='Total_time');
            app.dataYrange{idx,3} = app.Ymin_tot.Value;
            app.dataYrange{idx,4} = app.Ymax_tot.Value;
        end

        % Button pushed function: ResetAll
        function ResetAllButtonPushed(app, event)
            % update Yrange data
            updateYrangeVals(app,1);

            % populate values for first behavior shown
            populate_Yranges(app);

            % update color button
            app.ResetAll.BackgroundColor = [0.94,0.94,0.94];
        end

        % Button pushed function: AddStim
        function AddStimButtonPushed(app, event)
            % add row to Bins Table
            % Get current table data
            currentData = app.Bins.Data;

            % Define a new empty row (customize this for your table format)
            newRow = {size(currentData,1)+1,'',[],[]};
            % Append the new row to the table
            currentData = [currentData; newRow];

            % Update the table
            app.Bins.Data = currentData;

            % update ResetAll button
            app.ResetAll.BackgroundColor = [0.8 0.5 0.5];
        end

        % Value changed function: BinCheckBox
        function BinCheckBoxValueChanged(app, event)
            value = app.BinCheckBox.Value;

            if value

                % enable Bins table
                app.Bins.Enable = 'on';
                app.AddStim.Enable = 'on';
                app.RemoveStim.Enable = 'on';

                % create Bins table
                if isempty(app.Bins.Data)
                    binData = {1,'',app.timerangelimit(1),app.timerangelimit(2)};
                    app.Bins.Data = binData;
                end

                % Recalculate Yranges if at least one bin has been specified
                emptycells = cellfun(@(x) isempty(x),app.Bins.Data);
                if sum(emptycells)==0
                    updateYrangeVals(app,1);
                    %app.currYrange = app.dataYrange;
                    populate_Yranges(app);
                end

                % TODO - repopulate Ymin Ymax Vals in GUI

                %app.ResetAll.BackgroundColor = [0.8 0.5 0.5];

                %compareYranges

            else
                % disable Bins
                app.Bins.Enable = 'off';
                app.BinCheckBox.Value = false;
                app.AddStim.Enable = 'off';
                app.RemoveStim.Enable = 'off';

                % Recalculate Yranges
                updateYrangeVals(app,1);
                populate_Yranges(app);


            end
        end

        % Button pushed function: RemoveStim
        function RemoveStimButtonPushed(app, event)
            % Get the current table data
            currentData = app.Bins.Data;

            % Get selected row (assuming user selects via a separate UI control)
            selectedRow = app.Bins.Selection;

            % Check if a row is selected
            if isempty(selectedRow)
                uialert(app.UIFigure, 'Please select a row to delete.', 'No Row Selected');
                return;
            end

            % Extract the row index
            rowToDelete = selectedRow(1);  % Get first selected row index

            % Remove the selected row
            currentData(rowToDelete, :) = [];

            % Update the table data
            app.Bins.Data = currentData;

            % if there are no rows, disable bins
            if isempty(app.Bins.Data)
                app.Bins.Enable = 'off';
                app.BinCheckBox.Value = false;
                app.AddStim.Enable = 'off';
                app.RemoveStim.Enable = 'off';
            end

            % update ResetAll button
            app.ResetAll.BackgroundColor = [0.8 0.5 0.5];
        end

        % Cell edit callback: Bins
        function BinsCellEdit(app, event)

            % Get edited row and column indices
            row = event.Indices(1);
            col = event.Indices(2);

            % Get the new value entered by the user
            newValue = event.NewData;

            % Get the old value before the edit
            oldValue = event.PreviousData;

            % Define range for column 2 (Quantity)
            minValue = app.timerangelimit(1);
            maxValue = app.timerangelimit(2);

            % Check if the edited column is numeric (e.g., Quantity column)
            if col == 3 || col == 4
                if isnan(newValue) || newValue < minValue || newValue > maxValue
                    % Show an error message
                    uialert(app.UIFigure, sprintf('Invalid input! Enter a number between %d and %d.', minValue, maxValue), 'Error');

                    % Revert to old value
                    app.Bins.Data{row, col} = oldValue;
                end
            end

            % Check that the End is greater than Start for each row
            if col == 3 && ~isempty(app.Bins.Data{row,4}) && newValue >= app.Bins.Data{row,4}
                % Show error message
                uialert(app.UIFigure, sprintf('Invalid input! Number must be less %d.', app.Bins.Data{row,4}), 'Error');
                % Revert to old value
                app.Bins.Data{row,col} = oldValue;
            end

            if col ==4 && ~isempty(app.Bins.Data{row,3}) && newValue <= app.Bins.Data{row,3}
                % Show error message
                uialert(app.UIFigure, sprintf('Invalid input! Number must be greater than %d.', app.Bins.Data{row,3}), 'Error');
                % Revert to old value
                app.Bins.Data{row,col} = oldValue;
            end

            % sort rows and adjust row numbers
            if col == 3
                app.Bins.Data = sortrows(app.Bins.Data,3);
                for b = 1:size(app.Bins.Data,1)
                    app.Bins.Data{b,1} = b;
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotselectionPanel = uipanel(app.UIFigure);
            app.PlotselectionPanel.Title = 'Plot selection';
            app.GenotypestoplotListBoxLabel = uilabel(app.PlotselectionPanel);
            app.GenotypestoplotListBoxLabel.Text = 'Genotypes to plot';
            app.GenotypestoplotList = uilistbox(app.PlotselectionPanel);
            app.GenotypestoplotList.Items = {''};
            app.GenotypestoplotList.Multiselect = 'on';
            app.GenotypestoplotList.ValueChangedFcn = createCallbackFcn(app, @GenotypestoplotListHistValueChanged, true);
            app.GenotypestoplotList.Value = {};
            app.BehaviorstoplotListBoxLabel = uilabel(app.PlotselectionPanel);
            app.BehaviorstoplotListBoxLabel.Text = 'Behaviors to plot';
            app.BehaviorstoplotList = uilistbox(app.PlotselectionPanel);
            app.BehaviorstoplotList.Items = {};
            app.BehaviorstoplotList.Multiselect = 'on';
            app.BehaviorstoplotList.ValueChangedFcn = createCallbackFcn(app, @BehaviorstoplotListHistValueChanged, true);
            app.BehaviorstoplotList.Value = {};
            app.CommonStart = uieditfield(app.PlotselectionPanel, 'numeric');
            app.CommonStart.ValueChangedFcn = createCallbackFcn(app, @CommonStartValueChanged, true);
            app.CommonStartLabel = uilabel(app.PlotselectionPanel);
            app.CommonStartLabel.Text = 'Start';
            app.CommonEnd = uieditfield(app.PlotselectionPanel, 'numeric');
            app.CommonEnd.ValueChangedFcn = createCallbackFcn(app, @CommonEndValueChanged, true);
            app.CommonEndLabel = uilabel(app.PlotselectionPanel);
            app.CommonEndLabel.Text = 'End';
            app.TimeRangesecLabel_2 = uilabel(app.PlotselectionPanel);
            app.TimeRangesecLabel_2.Text = 'Time Range (sec)';
            app.StartBuffersecEditFieldLabel = uilabel(app.PlotselectionPanel);
            app.StartBuffersecEditFieldLabel.Text = 'Start Buffer (sec)';
            app.StartBuffersecEditField = uieditfield(app.PlotselectionPanel, 'numeric');
            app.StartBuffersecEditField.Limits = [0 Inf];
            app.StartBuffersecEditField.ValueChangedFcn = createCallbackFcn(app, @StartBuffersecEditFieldValueChanged, true);
            app.StartBuffersecEditField.Value = 20;
            app.Bins = uitable(app.PlotselectionPanel);
            app.Bins.ColumnName = {'Bin'; 'Name'; 'Start'; 'End'};
            app.Bins.RowName = {};
            app.Bins.ColumnEditable = [false true true true];
            app.Bins.CellEditCallback = createCallbackFcn(app, @BinsCellEdit, true);
            app.Bins.Multiselect = 'off';
            app.Bins.Enable = 'off';
            app.AddStim = uibutton(app.PlotselectionPanel, 'push');
            app.AddStim.ButtonPushedFcn = createCallbackFcn(app, @AddStimButtonPushed, true);
            app.AddStim.Enable = 'off';
            app.AddStim.Text = '+';
            app.RemoveStim = uibutton(app.PlotselectionPanel, 'push');
            app.RemoveStim.ButtonPushedFcn = createCallbackFcn(app, @RemoveStimButtonPushed, true);
            app.RemoveStim.Enable = 'off';
            app.RemoveStim.Text = '-';
            app.BinCheckBox = uicheckbox(app.PlotselectionPanel);
            app.BinCheckBox.ValueChangedFcn = createCallbackFcn(app, @BinCheckBoxValueChanged, true);
            app.BinCheckBox.Text = 'Bin Data (sec)';
            app.MatFilesPanel = uipanel(app.UIFigure);
            app.MatFilesPanel.Title = 'Mat Files';
            app.GenotypeEditFieldLabel = uilabel(app.MatFilesPanel);
            app.GenotypeEditFieldLabel.Text = 'Genotype';
            app.GenotypeEditField = uieditfield(app.MatFilesPanel, 'text');
            app.GenotypeEditField.ValueChangedFcn = createCallbackFcn(app, @GenotypeEditFieldValueChanged, true);
            app.StartLabel = uilabel(app.MatFilesPanel);
            app.StartLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.MatFilesPanel, 'numeric');
            app.EndEditFieldLabel = uilabel(app.MatFilesPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.MatFilesPanel, 'numeric');
            app.TimeRangesecLabel = uilabel(app.MatFilesPanel);
            app.TimeRangesecLabel.Text = 'Time Range (sec)';
            app.BehaviorsListBoxLabel = uilabel(app.MatFilesPanel);
            app.BehaviorsListBoxLabel.Text = 'Behaviors';
            app.BehaviorsListBox = uilistbox(app.MatFilesPanel);
            app.BehaviorsListBox.Items = {};
            app.BehaviorsListBox.Value = {};
            app.AddfilesButton = uibutton(app.MatFilesPanel, 'push');
            app.AddfilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddfilesButtonPushed, true);
            app.AddfilesButton.Text = 'Add files';
            app.FilesListBoxLabel = uilabel(app.MatFilesPanel);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.MatFilesPanel);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.RemovefilesButton = uibutton(app.MatFilesPanel, 'push');
            app.RemovefilesButton.ButtonPushedFcn = createCallbackFcn(app, @RemovefilesButtonPushed, true);
            app.RemovefilesButton.Enable = 'off';
            app.RemovefilesButton.Visible = 'off';
            app.RemovefilesButton.Text = 'Remove files';
            app.ProcesstxtFilesButton = uibutton(app.MatFilesPanel, 'push');
            app.ProcesstxtFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesstxtFilesButtonPushed, true);
            app.ProcesstxtFilesButton.Text = 'Process txt Files';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BoxplotsTab = uitab(app.TabGroup);
            app.BoxplotsTab.Title = 'Boxplots';
            app.DatatoPlotPanel = uipanel(app.BoxplotsTab);
            app.DatatoPlotPanel.Title = 'Data to Plot';
            app.Reset_freq = uibutton(app.DatatoPlotPanel, 'push');
            app.Reset_freq.ButtonPushedFcn = createCallbackFcn(app, @Reset_freqButtonPushed2, true);
            app.Reset_freq.Text = 'Reset';
            app.Reset_dur = uibutton(app.DatatoPlotPanel, 'push');
            app.Reset_dur.ButtonPushedFcn = createCallbackFcn(app, @Reset_durButtonPushed2, true);
            app.Reset_dur.Text = 'Reset';
            app.Reset_per = uibutton(app.DatatoPlotPanel, 'push');
            app.Reset_per.ButtonPushedFcn = createCallbackFcn(app, @Reset_perButtonPushed2, true);
            app.Reset_per.Text = 'Reset';
            app.Reset_tot = uibutton(app.DatatoPlotPanel, 'push');
            app.Reset_tot.ButtonPushedFcn = createCallbackFcn(app, @Reset_totButtonPushed2, true);
            app.Reset_tot.Text = 'Reset';
            app.ResetAll = uibutton(app.DatatoPlotPanel, 'push');
            app.ResetAll.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAll.Text = 'Reset All';
            app.BoutFrequencyCheckBox = uicheckbox(app.DatatoPlotPanel);
            app.BoutFrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoutFrequencyCheckBoxValueChanged, true);
            app.BoutFrequencyCheckBox.Text = 'Bout Frequency';
            app.BoutDurationCheckBox = uicheckbox(app.DatatoPlotPanel);
            app.BoutDurationCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoutDurationCheckBoxValueChanged, true);
            app.BoutDurationCheckBox.Text = 'Bout Duration';
            app.PercentTimeCheckBox = uicheckbox(app.DatatoPlotPanel);
            app.PercentTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PercentTimeCheckBoxValueChanged, true);
            app.PercentTimeCheckBox.Text = 'Percent Time';
            app.TotalTimeCheckBox = uicheckbox(app.DatatoPlotPanel);
            app.TotalTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TotalTimeCheckBoxValueChanged, true);
            app.TotalTimeCheckBox.Text = 'Total Time';
            app.MetricBox = uilabel(app.DatatoPlotPanel);
            app.MetricBox.Text = 'Metric';
            app.Ymin_freq = uieditfield(app.DatatoPlotPanel, 'numeric');
            app.Ymin_freq.Limits = [0 Inf];
            app.Ymin_freq.ValueChangedFcn = createCallbackFcn(app, @Ymin_freqValueChanged, true);
            app.Ymin_freq.Enable = 'off';
            app.Ymin_dur = uieditfield(app.DatatoPlotPanel, 'numeric');
            app.Ymin_dur.Limits = [0 Inf];
            app.Ymin_dur.ValueChangedFcn = createCallbackFcn(app, @Ymin_durValueChanged, true);
            app.Ymin_dur.Enable = 'off';
            app.Ymin_per = uieditfield(app.DatatoPlotPanel, 'numeric');
            app.Ymin_per.Limits = [0 100];
            app.Ymin_per.ValueChangedFcn = createCallbackFcn(app, @Ymin_perValueChanged, true);
            app.Ymin_per.Editable = 'off';
            app.Ymin_per.Enable = 'off';
            app.Ymin_tot = uieditfield(app.DatatoPlotPanel, 'numeric');
            app.Ymin_tot.Limits = [0 Inf];
            app.Ymin_tot.ValueChangedFcn = createCallbackFcn(app, @Ymin_totValueChanged, true);
            app.Ymin_tot.Enable = 'off';
            app.YMinLabel = uilabel(app.DatatoPlotPanel);
            app.YMinLabel.Text = 'Y Min';
            app.Ymax_freq = uieditfield(app.DatatoPlotPanel, 'numeric');
            app.Ymax_freq.Limits = [0 Inf];
            app.Ymax_freq.ValueChangedFcn = createCallbackFcn(app, @Ymax_freqValueChanged, true);
            app.Ymax_freq.Enable = 'off';
            app.Ymax_dur = uieditfield(app.DatatoPlotPanel, 'numeric');
            app.Ymax_dur.Limits = [0 Inf];
            app.Ymax_dur.ValueChangedFcn = createCallbackFcn(app, @Ymax_durValueChanged, true);
            app.Ymax_dur.Enable = 'off';
            app.Ymax_per = uieditfield(app.DatatoPlotPanel, 'numeric');
            app.Ymax_per.Limits = [0 100];
            app.Ymax_per.ValueChangedFcn = createCallbackFcn(app, @Ymax_perValueChanged, true);
            app.Ymax_per.Enable = 'off';
            app.Ymax_tot = uieditfield(app.DatatoPlotPanel, 'numeric');
            app.Ymax_tot.Limits = [0 Inf];
            app.Ymax_tot.ValueChangedFcn = createCallbackFcn(app, @Ymax_totValueChanged, true);
            app.Ymax_tot.Enable = 'off';
            app.YMaxLabel = uilabel(app.DatatoPlotPanel);
            app.YMaxLabel.Text = 'Y Max';
            app.SelectBehaviorDropDown = uidropdown(app.DatatoPlotPanel);
            app.SelectBehaviorDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectBehaviorDropDownValueChanged, true);
            app.GroupingDropDownLabel = uilabel(app.BoxplotsTab);
            app.GroupingDropDownLabel.Text = 'Grouping';
            app.GroupingDropDown = uidropdown(app.BoxplotsTab);
            app.GroupingDropDown.Items = {'Behavior', 'Genotype'};
            app.GroupingDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupingDropDownValueChanged, true);
            app.GroupingDropDown.Value = 'Behavior';
            app.ControlDropDownLabel = uilabel(app.BoxplotsTab);
            app.ControlDropDownLabel.Text = 'Control';
            app.ControlDropDown = uidropdown(app.BoxplotsTab);
            app.ControlDropDown.Items = {'None'};
            app.ControlDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlDropDownValueChanged, true);
            app.ControlDropDown.Value = 'None';
            app.GraphDropDownLabel = uilabel(app.BoxplotsTab);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.BoxplotsTab);
            app.GraphDropDown.Items = {'Box plot'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Value = 'Box plot';
            app.ExportdatatoExcelCheckBox = uicheckbox(app.BoxplotsTab);
            app.ExportdatatoExcelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportdatatoExcelCheckBoxValueChanged, true);
            app.ExportdatatoExcelCheckBox.Text = 'Export data to Excel';
            app.PlotBox = uibutton(app.BoxplotsTab, 'push');
            app.PlotBox.ButtonPushedFcn = createCallbackFcn(app, @PlotBoxPushed, true);
            app.PlotBox.Text = {'Plot'; ''};
            app.SetButton = uibutton(app.BoxplotsTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.SavePathEditFieldLabel = uilabel(app.BoxplotsTab);
            app.SavePathEditFieldLabel.Text = 'Save Path:';
            app.SavePathEditField = uieditfield(app.BoxplotsTab, 'text');
            app.StatisticsDropDownLabel = uilabel(app.BoxplotsTab);
            app.StatisticsDropDownLabel.Text = 'Statistics';
            app.StatisticsDropDown = uidropdown(app.BoxplotsTab);
            app.StatisticsDropDown.Items = {'None', 'Parametric', 'Non-parametric'};
            app.StatisticsDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticsDropDownValueChanged, true);
            app.StatisticsDropDown.Value = 'None';
            app.HistogramsTab = uitab(app.TabGroup);
            app.HistogramsTab.Title = 'Histograms';
            app.FrameRatefpsEditFieldLabel = uilabel(app.HistogramsTab);
            app.FrameRatefpsEditFieldLabel.Text = 'Frame Rate (fps)';
            app.FrameRatefpsEditField = uieditfield(app.HistogramsTab, 'numeric');
            app.FrameRatefpsEditField.Value = 30;
            app.SmoothingsecEditFieldLabel = uilabel(app.HistogramsTab);
            app.SmoothingsecEditFieldLabel.Text = 'Smoothing (sec)';
            app.SmoothingsecEditField = uieditfield(app.HistogramsTab, 'numeric');
            app.SmoothingsecEditField.Value = 1;
            app.StimulusPanel = uipanel(app.HistogramsTab);
            app.StimulusPanel.Title = 'Stimulus';
            app.PlotStimulusCheckBox = uicheckbox(app.StimulusPanel);
            app.PlotStimulusCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotStimulusCheckBoxValueChanged, true);
            app.PlotStimulusCheckBox.Text = 'Plot Stimulus';
            app.stimtimes = uitable(app.StimulusPanel);
            app.stimtimes.ColumnName = {'Start'; 'End'; 'Duration'};
            app.stimtimes.RowName = {};
            app.stimtimes.ColumnEditable = [false false false];
            app.stimtimes.CellEditCallback = createCallbackFcn(app, @stimtimesCellEdit, true);
            app.stimtimes.Multiselect = 'off';
            app.StimulustimessecLabel = uilabel(app.StimulusPanel);
            app.StimulustimessecLabel.Text = 'Stimulus times (sec)';
            app.REditFieldLabel = uilabel(app.StimulusPanel);
            app.REditFieldLabel.Text = 'R';
            app.StimREditField = uieditfield(app.StimulusPanel, 'numeric');
            app.StimREditField.Limits = [0 1];
            app.StimREditField.ValueChangedFcn = createCallbackFcn(app, @StimREditFieldValueChanged, true);
            app.GEditFieldLabel = uilabel(app.StimulusPanel);
            app.GEditFieldLabel.Text = 'G';
            app.StimGEditField = uieditfield(app.StimulusPanel, 'numeric');
            app.StimGEditField.Limits = [0 1];
            app.StimGEditField.ValueChangedFcn = createCallbackFcn(app, @StimGEditFieldValueChanged, true);
            app.BEditFieldLabel = uilabel(app.StimulusPanel);
            app.BEditFieldLabel.Text = 'B';
            app.StimBEditField = uieditfield(app.StimulusPanel, 'numeric');
            app.StimBEditField.Limits = [0 1];
            app.StimBEditField.ValueChangedFcn = createCallbackFcn(app, @StimBEditFieldValueChanged, true);
            app.StimColorLamp = uilamp(app.StimulusPanel);
            app.StimulusColorLabel = uilabel(app.StimulusPanel);
            app.StimulusColorLabel.Text = 'Stimulus Color';
            app.addStim = uibutton(app.StimulusPanel, 'push');
            app.addStim.Enable = 'off';
            app.addStim.Visible = 'off';
            app.addStim.Text = '+';
            app.removeStim = uibutton(app.StimulusPanel, 'push');
            app.removeStim.Enable = 'off';
            app.removeStim.Visible = 'off';
            app.removeStim.Text = '-';
            app.TLabel = uilabel(app.StimulusPanel);
            app.TLabel.Text = 'T';
            app.StimTEditField = uieditfield(app.StimulusPanel, 'numeric');
            app.StimTEditField.Limits = [0 1];
            app.StimTEditField.ValueChangedFcn = createCallbackFcn(app, @StimTEditFieldValueChanged, true);
            app.StimTEditField.Value = 0.25;
            app.PlotHistogram = uibutton(app.HistogramsTab, 'push');
            app.PlotHistogram.ButtonPushedFcn = createCallbackFcn(app, @PlotHistogramButtonPushed, true);
            app.PlotHistogram.Text = {'Plot'; ''};
            app.EthogramsTab = uitab(app.TabGroup);
            app.EthogramsTab.Title = 'Ethograms';
            app.PlotEthogram = uibutton(app.EthogramsTab, 'push');
            app.PlotEthogram.ButtonPushedFcn = createCallbackFcn(app, @PlotEthogramButtonPushed, true);
            app.PlotEthogram.Text = {'Plot'; ''};
            app.EthogramtypeDropDownLabel = uilabel(app.EthogramsTab);
            app.EthogramtypeDropDownLabel.Text = 'Ethogram type:';
            app.EthogramtypeDropDown = uidropdown(app.EthogramsTab);
            app.EthogramtypeDropDown.Items = {'Compact', 'Expanded'};
            app.EthogramtypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @EthogramtypeDropDownOpening, true);
            app.EthogramtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EthogramtypeDropDownValueChanged, true);
            app.EthogramtypeDropDown.Value = 'Compact';
            app.PlotStimulusCheckBox_etho = uicheckbox(app.EthogramsTab);
            app.PlotStimulusCheckBox_etho.ValueChangedFcn = createCallbackFcn(app, @PlotStimulusCheckBox_ethoValueChanged, true);
            app.PlotStimulusCheckBox_etho.Text = 'Plot Stimulus';
            app.StimulusColorLabel_etho = uilabel(app.EthogramsTab);
            app.StimulusColorLabel_etho.Text = 'Stimulus Color';
            app.StimColorLamp_etho = uilamp(app.EthogramsTab);
            app.REditFieldLabel_2 = uilabel(app.EthogramsTab);
            app.REditFieldLabel_2.Text = 'R';
            app.StimREditField_etho = uieditfield(app.EthogramsTab, 'numeric');
            app.StimREditField_etho.Limits = [0 1];
            app.StimREditField_etho.ValueChangedFcn = createCallbackFcn(app, @StimREditField_ethoValueChanged, true);
            app.GEditFieldLabel_2 = uilabel(app.EthogramsTab);
            app.GEditFieldLabel_2.Text = 'G';
            app.StimGEditField_etho = uieditfield(app.EthogramsTab, 'numeric');
            app.StimGEditField_etho.Limits = [0 1];
            app.StimGEditField_etho.ValueChangedFcn = createCallbackFcn(app, @StimGEditField_ethoValueChanged, true);
            app.BEditFieldLabel_2 = uilabel(app.EthogramsTab);
            app.BEditFieldLabel_2.Text = 'B';
            app.StimBEditField_etho = uieditfield(app.EthogramsTab, 'numeric');
            app.StimBEditField_etho.Limits = [0 1];
            app.StimBEditField_etho.ValueChangedFcn = createCallbackFcn(app, @StimBEditField_ethoValueChanged, true);
            app.FlyGroomAFlyGroomingAnalysisLabel = uilabel(app.UIFigure);
            app.FlyGroomAFlyGroomingAnalysisLabel.Text = 'FlyGroomA - Fly Grooming Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlyGroomAv2_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end