classdef process_grooming < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProcessGroomingDataFilesPanel  matlab.ui.container.Panel
        LoadParamsButton               matlab.ui.control.Button
        SavepathEditField              matlab.ui.control.EditField
        SavepathEditFieldLabel         matlab.ui.control.Label
        MatfilenameEditField           matlab.ui.control.EditField
        MatfilenameEditFieldLabel      matlab.ui.control.Label
        SetButton                      matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        ProcessFilesButton             matlab.ui.control.Button
        FileTypeDropDown               matlab.ui.control.DropDown
        FileTypeDropDownLabel          matlab.ui.control.Label
        FilesforoneconditionListBox    matlab.ui.control.ListBox
        FilesforoneconditionListBoxLabel  matlab.ui.control.Label
        AddfilesButton                 matlab.ui.control.Button
        TimeRangesecLabel              matlab.ui.control.Label
        EndEditField                   matlab.ui.control.NumericEditField
        EndEditFieldLabel              matlab.ui.control.Label
        StartEditField                 matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        data % txt data
        saveFname  % save name for mat file
        savePath   % save path
        fileNames = {}; % text files to process
        filePaths = {} % full paths to files to process
        fileType = 'Noldus'; % type of txt file
        startTime = 0; % when to start processing in sec
        endTime = 0; % when to stop processing in sec
        behaviorClasses % behavior classes defined from param file
        behavTable      % table of behaviors and colors from param file
        behavList       % list of behaviors defined from param file
        paramsFname = ''; % path and name of params.csv
    end

    properties (Access = public)


    end

    methods (Access = private)

        function compileGroomingData_allcomp(app)
            %COMPILEGROOMINGDATA formats data from manually-scored (VCode or Noldus) grooming experiments
            %into matlab readable structures for processing by
            %GROOMING_ANALYSIS
            %
            %INPUT
            %   EVTS.TXT files that user chooses. Should be a group of experiments to
            %   be grouped together for analysis (i.e., genotype)
            %
            %   filetype = 'VCode' or 'Noldus' or 'Boris' or 'JAABA'
            %
            %   PARAMS CSV FILE (to be added)
            %
            %OUTPUT
            %   EVENTS - structure 1xnumber of evts.txt files.
            %       Fields include:
            %       file_path (path to evts.txt)
            %       behaviors (start time & duration in seconds)
            %
            %   STATS - 1x1 structure with descriptive statistics for all experiments
            %
            %   GENOTYPE - user-specified genotype (inherit form GUI in future)
            %
            %   TIMERANGE - user-specified timerange of analysis (inherit form GUI in
            %   future)

            %% Pull up list of txt files to process

            % number of files to process
            fileCount = length(app.filePaths);

            param_behavs = unique(app.behavTable.Behavior);

            % convert param_behavs to standard format (no caps, no spaces)

            for i = 1:length(param_behavs)
                new_name = behaviorToFieldName(app,param_behavs{i});
                if ~strcmp(param_behavs{i},new_name)
                    param_behavs{i} = new_name;
                end
            end
            %
            %             end

            %% check that names in params file match names in txt files

            %% Process files
            switch app.fileType

                case 'Noldus'
                    %% Convert Noldus to mat files

                    % Load the events from the file(s).
                    for fileIndex = 1:fileCount
                        events(fileIndex).file_path = app.filePaths{fileIndex};

                        % Read Noldus text file & format
                        T = readtable(app.filePaths{fileIndex},'Delimiter',';');
                        T.Behavior = categorical(T.Behavior);
                        T.Event_Type = categorical(T.Event_Type);

                        % Remove 'state point' events from table
                        rmIdx = T.Event_Type=='State point';
                        T = T(~rmIdx,:);

                        %lowerbehavs = string(T.Behavior); % convert behaviors to string so it can be searched in case insensitive manner
                        % convert param_behavs to standard format (no caps, no spaces)
                        % alert user of differences
                        behaviorNames = cellstr(T.Behavior);
                        for i = 1:length(behaviorNames)
                            new_name = behaviorToFieldName(app,behaviorNames{i});
                            if ~strcmp(behaviorNames{i},new_name)
                                behaviorNames{i} = new_name;
                            end
                        end

                        % replace Behaviors in Table with formatted names
                        T.Behavior = categorical(behaviorNames);

                        % Get the start time and duration of all events for each type of behavior.
                        uniqueBehaviors = unique(T.Behavior);

                        % remove any <undefined> entries
                        % TODO: check what this does
                        uniqueBehaviors = uniqueBehaviors(~isundefined(uniqueBehaviors));

                        % check to see if any of the uniqueBehaviors are
                        % not found in param_behavs and alert user.
                        notfound = ~ismember(uniqueBehaviors,param_behavs);
                        InvalidNames = cellstr(uniqueBehaviors(notfound));
                        if ~isempty(InvalidNames)
                            uialert(app.UIFigure, ['The following behavior names were not found in your parameters file. Make sure to check spelling and formatting.' InvalidNames'], 'Invalid Behavior Names!');
                        end

                        % for b = 1:length(uniqueBehaviors)
                        %     behavior = char(uniqueBehaviors(b));
                        for b = 1:length(param_behavs)
                            behavior = char(param_behavs(b));

                            % Get the indices of the events for this behavior.
                            behaviorIdxs = T.Behavior==behavior; %TODO categorical searches are case sensitive!!
                            %behaviorIdxs = strcmpi(lowerbehavs, behavior);

                            if sum(behaviorIdxs)==0 % no events found
                                % Store the start times and durations for the behavior.
                                events(fileIndex).(behaviorToFieldName(app,behavior)) = [];
                            else
                                % Get the start times and durations at those indices.
                                startTimes = T.Time_Relative_sf(T.Behavior==behavior & T.Event_Type=='State start');
                                stopTimes = T.Time_Relative_sf(T.Behavior==behavior & T.Event_Type=='State stop');
                                %durations = stopTimes - startTimes;
                                durations = T.Duration_sf(T.Behavior==behavior & T.Event_Type=='State start');

                                % Restrict to the events within the time window.
                                % Eliminate events that completely
                                % outside window (startTime>app.endTime | stopTime<app.startTimes)
                                idx = ones(1,length(startTimes));
                                for i = 1:length(startTimes)
                                    if startTimes(i) > app.endTime || stopTimes(i) < app.startTime
                                        % if event starts after app.endTime or ends before app.startTime, eliminate
                                        idx(i) = 0;
                                    elseif startTimes(i) < app.startTime && stopTimes(i) > app.endTime
                                        % if event starts before app.startTime and ends after app.endTime (spans
                                        % entire window), trim to window
                                        startTimes(i) = app.startTime;
                                        stopTimes(i) = app.endTime;
                                        durations(i) = app.endTime - app.startTime;
                                    elseif startTimes(i) < app.startTime && stopTimes(i) < app.endTime
                                        % if event start before app.startTime but ends before app.endTime, start
                                        % at app.startTime
                                        startTimes(i) = app.startTime;
                                        durations(i) = stopTimes(i)-app.startTime;
                                    elseif startTimes(i) < app.endTime && stopTimes(i) > app.endTime
                                        % if event starts before app.endTime and ends after app.endTime, set
                                        % end to app.endTime
                                        stopTimes(i) = app.endTime;
                                        durations(i) = app.endTime - startTimes(i);
                                    end
                                end
                                idx = logical(idx);
                                startTimes = startTimes(idx);
                                stopTimes = stopTimes(idx);
                                durations = durations(idx);
                                
                                % Store the start times and durations for the behavior.
                                %events(fileIndex).(behaviorToFieldName(app,behavior)) = horzcat(startTimes(idxs), durations(idxs)); %#ok<AGROW>
                                events(fileIndex).(behaviorToFieldName(app,behavior)) = horzcat(startTimes, durations); %#ok<AGROW>
                            end

                        end
                    end

                    % Duplicate events for classes
                    behaviorClassNames = fieldnames(app.behaviorClasses);
                    for c = 1:length(behaviorClassNames)
                        behaviorClass = behaviorClassNames{c};
                        behaviors = app.behaviorClasses.(behaviorClass).behaviors;
                        %behaviorClass = ['class_' behaviorClass]; %#ok<AGROW>
                        for f = 1:length(events)
                            events(f).(behaviorClass) = []; %#ok<AGROW>

                            for b = 1:length(behaviors)
                                behavior = behaviors{b};
                                if isfield(events(f), behavior)
                                    behaviorEvents = events(f).(behavior);

                                    if ~isempty(behaviorEvents)
                                        events(f).(behaviorClass) = vertcat(events(f).(behaviorClass), behaviorEvents); %#ok<AGROW>
                                    end
                                end
                            end

                            events(f).(behaviorClass) = sortrows(events(f).(behaviorClass)); %#ok<AGROW>
                        end
                    end

%% BORIS conversion
                case 'BORIS'
                    %% Convert BORIS to mat files

                    % Load the events from the file(s).
                    for fileIndex = 1:fileCount
                        events(fileIndex).file_path = app.filePaths{fileIndex};

                        % Read BORIS xlsx file & format
                        T = readtable(app.filePaths{fileIndex});
                        T.Behavior = categorical(T.Behavior);
                        T.BehaviorType = categorical(T.BehaviorType);


                        %lowerbehavs = string(T.Behavior); % convert behaviors to string so it can be searched in case insensitive manner
                        % convert param_behavs to standard format (no caps, no spaces)
                        % alert user of differences
                        behaviorNames = cellstr(T.Behavior);
                        for i = 1:length(behaviorNames)
                            new_name = behaviorToFieldName(app,behaviorNames{i});
                            if ~strcmp(behaviorNames{i},new_name)
                                behaviorNames{i} = new_name;
                            end
                        end

                        % replace Behaviors in Table with formatted names
                        T.Behavior = categorical(behaviorNames);

                        % Get the start time and duration of all events for each type of behavior.
                        uniqueBehaviors = unique(T.Behavior);

                        % remove any <undefined> entries
                        % TODO: check what this does
                        uniqueBehaviors = uniqueBehaviors(~isundefined(uniqueBehaviors));

                        % check to see if any of the uniqueBehaviors are
                        % not found in param_behavs and alert user.
                        notfound = ~ismember(uniqueBehaviors,param_behavs);
                        InvalidNames = cellstr(uniqueBehaviors(notfound));
                        if ~isempty(InvalidNames)
                            uialert(app.UIFigure, ['The following behavior names were not found in your parameters file. Make sure to check spelling and formatting.' InvalidNames'], 'Invalid Behavior Names!');
                        end

                        % for b = 1:length(uniqueBehaviors)
                        %     behavior = char(uniqueBehaviors(b));
                        for b = 1:length(param_behavs)
                            behavior = char(param_behavs(b));

                            % Get the indices of the events for this behavior.
                            behaviorIdxs = T.Behavior==behavior; %TODO categorical searches are case sensitive!!
                            %behaviorIdxs = strcmpi(lowerbehavs, behavior);

                            if sum(behaviorIdxs)==0 % no events found
                                % Store the start times and durations for the behavior.
                                events(fileIndex).(behaviorToFieldName(app,behavior)) = [];
                            else
                                % Get the start times and durations at those indices.
                                
                                startTimes = T.Time(T.Behavior==behavior & T.BehaviorType=='START');
                                stopTimes = T.Time(T.Behavior==behavior & T.BehaviorType=='STOP');
                                
                                if iscellstr(startTimes) || isstring (startTimes)
                                startTimes = str2double(startTimes);
                                stopTimes = str2double(stopTimes);
                                end

                                % check to see if there are the same number
                                % of starts and stops
                                nStarts=length(startTimes);
                                nStops = length(stopTimes);

                                if nStarts > nStops
                                   % start w/o stop (most likely)
                                   if nStarts-nStops > 1
                                       % display error to user saying there
                                       % are multiple unclose events
                                   else 
                                       stopTimes(end+1) = app.endTime;
                                   end
                                elseif nStarts < nStops
                                    % stop w/o start (unlikely)
                                    if nStops-nStarts > 1
                                        % display error to user saying
                                        % there are multiple unstarted
                                        % events
                                    else
                                        startTimes = [app.startTime ; startTimes];
                                    end
                              
                                end
                                fileIndex
                                durations = stopTimes - startTimes;
                                
                                % Could there be start or stops without a partner?

                                % Restrict to the events within the time window.
                                % Eliminate events that completely
                                % outside window (startTime>app.endTime | stopTime<app.startTimes)
                                idx = ones(1,length(startTimes));
                                for i = 1:length(startTimes)
                                    if startTimes(i) > app.endTime || stopTimes(i) < app.startTime
                                        % if event starts after app.endTime or ends before app.startTime, eliminate
                                        idx(i) = 0;
                                    elseif startTimes(i) < app.startTime && stopTimes(i) > app.endTime
                                        % if event starts before app.startTime and ends after app.endTime (spans
                                        % entire window), trim to window
                                        startTimes(i) = app.startTime;
                                        stopTimes(i) = app.endTime;
                                        durations(i) = app.endTime - app.startTime;
                                    elseif startTimes(i) < app.startTime && stopTimes(i) < app.endTime
                                        % if event start before app.startTime but ends before app.endTime, start
                                        % at app.startTime
                                        startTimes(i) = app.startTime;
                                        durations(i) = stopTimes(i)-app.startTime;
                                    elseif startTimes(i) < app.endTime && stopTimes(i) > app.endTime
                                        % if event starts before app.endTime and ends after app.endTime, set
                                        % end to app.endTime
                                        stopTimes(i) = app.endTime;
                                        durations(i) = app.endTime - startTimes(i);
                                    end
                                end
                                idx = logical(idx);
                                startTimes = startTimes(idx);
                                %stopTimes = stopTimes(idx);
                                durations = durations(idx);
                                
                                % Store the start times and durations for the behavior.
                                %events(fileIndex).(behaviorToFieldName(app,behavior)) = horzcat(startTimes(idxs), durations(idxs)); %#ok<AGROW>
                                events(fileIndex).(behaviorToFieldName(app,behavior)) = horzcat(startTimes, durations); %#ok<AGROW>
                            end

                        end
                    end

                    % Duplicate events for classes
                    behaviorClassNames = fieldnames(app.behaviorClasses);
                    for c = 1:length(behaviorClassNames)
                        behaviorClass = behaviorClassNames{c};
                        behaviors = app.behaviorClasses.(behaviorClass).behaviors;
                        %behaviorClass = ['class_' behaviorClass]; %#ok<AGROW>
                        for f = 1:length(events)
                            events(f).(behaviorClass) = []; %#ok<AGROW>

                            for b = 1:length(behaviors)
                                behavior = behaviors{b};
                                if isfield(events(f), behavior)
                                    behaviorEvents = events(f).(behavior);

                                    if ~isempty(behaviorEvents)
                                        events(f).(behaviorClass) = vertcat(events(f).(behaviorClass), behaviorEvents); %#ok<AGROW>
                                    end
                                end
                            end

                            events(f).(behaviorClass) = sortrows(events(f).(behaviorClass)); %#ok<AGROW>
                        end
                    end

                    %% VCode conversion
                case 'VCode'

                    % Load the events from the file(s).
                    for fileIndex = 1:fileCount
                        events(fileIndex).file_path = app.filePaths{fileIndex}; %#ok<AGROW>

                        % Open and determine the type of file.
                        fid = fopen(events(fileIndex).file_path);
                        firstLine = fgets(fid);
                        frewind(fid);

                        try

                            if strncmp(firstLine, 'Offset:', 7)
                                % Read the start time (ms), duration (ms) and behavior name columns from a VCode file that looks like:
                                %   Offset: 0, Movie: MoviePathHere, DataFile: (null)
                                %   Tracks: Grooming, Feeding
                                %   Time,Duration,TrackName,comment
                                %
                                %   1260,824,Feeding,(null)
                                %   2349,408,Grooming,(null)
                                %   ...
                                % rawEvents = [{start times} {durations} {Behavior}
                                % {comment}]
                                rawEvents = textscan(fid, '%f %f %s %s', -1, 'HeaderLines', 4, 'delimiter', ',');

                                % Get the start time and duration of all events for each type of behavior.
                                uniqueBehaviors = setdiff(rawEvents{3}, {''}); %Behaviors found in file

                                % TODO: Convert behaviors from file to match behaviors in params
                                for b = 1:length(param_behavs)

                                    % behavior from param_behav to process
                                    behavior = param_behavs{b};

                                    % Get the indices of the events for this behavior.
                                    % behaviorIdxs = find(strcmpi(rawEvents{3}, fieldToBehaviorName(app,behavior)));
                                        behaviorIdxs = find(strcmpi(rawEvents{3}, behavior));
                                    if isempty(behaviorIdxs)% % no events found
                                        % Store the start times and durations for the behavior.
                                        events(fileIndex).(behaviorToFieldName(app,behavior)) = [];
                                    else

                                        % Store the start times and durations for the behavior.
                                        events(fileIndex).(behaviorToFieldName(app,behavior)) = [];

                                        % Calculate start and duration
                                        %behaviorIdxs = find(strcmp(rawEvents{3}, behavior));
                                        startTimes = rawEvents{1}(behaviorIdxs) / 1000;
                                        durations = rawEvents{2}(behaviorIdxs) / 1000;
                                        stopTimes = startTimes + durations;

                                        % Restrict to the events within the time window.
                                        % Eliminate events that completely
                                        % outside window (startTime>app.endTime | stopTime<app.startTimes)
                                        idx = ones(1,length(startTimes));
                                        for i = 1:length(startTimes)
                                            if startTimes(i) > app.endTime || stopTimes(i) < app.startTime
                                                % if event starts after app.endTime or ends before app.startTime, eliminate
                                                idx(i) = 0;
                                            elseif startTimes(i) < app.startTime && stopTimes(i) > app.endTime
                                                % if event starts before app.startTime and ends after app.endTime (spans
                                                % entire window), trim to window
                                                startTimes(i) = app.startTime;
                                                stopTimes(i) = app.endTime;
                                                durations(i) = app.endTime - app.startTime;
                                            elseif startTimes(i) < app.startTime && stopTimes(i) < app.endTime
                                                % if event start before app.startTime but ends before app.endTime, start
                                                % at app.startTime
                                                startTimes(i) = app.startTime;
                                                durations(i) = stopTimes(i)-app.startTime;
                                            elseif startTimes(i) < app.endTime && stopTimes(i) > app.endTime
                                                % if event starts before app.endTime and ends after app.endTime, set
                                                % end to app.endTime
                                                stopTimes(i) = app.endTime;
                                                durations(i) = app.endTime - startTimes(i);
                                            end
                                        end
                                        idx = logical(idx);
                                        startTimes = startTimes(idx);
                                        stopTimes = stopTimes(idx);
                                        durations = durations(idx);

                                        % Store the start times and durations for the behavior.

                                        % Store the start times and durations for the behavior.
                                        events(fileIndex).(behaviorToFieldName(app,behavior)) = horzcat(startTimes, durations); %#ok<AGROW>
                                    end
                                end
                            end
                        catch ME
                            fclose(fid);
                            rethrow(ME);
                        end
                        fclose(fid);
                    end

                    % Duplicate events for classes
                    behaviorClassNames = fieldnames(app.behaviorClasses);
                    for c = 1:length(behaviorClassNames)
                        behaviorClass = behaviorClassNames{c};
                        behaviors = app.behaviorClasses.(behaviorClass).behaviors;
                        %behaviorClass = ['class_' behaviorClass]; %#ok<AGROW>
                        for f = 1:length(events)
                            events(f).(behaviorClass) = []; %#ok<AGROW>

                            for b = 1:length(behaviors)
                                behavior = behaviors{b};
                                if isfield(events(f), behavior)
                                    behaviorEvents = events(f).(behavior);

                                    if ~isempty(behaviorEvents)
                                        events(f).(behaviorClass) = vertcat(events(f).(behaviorClass), behaviorEvents); %#ok<AGROW>
                                    end
                                end
                            end

                            events(f).(behaviorClass) = sortrows(events(f).(behaviorClass)); %#ok<AGROW>
                        end
                    end

                case 'JAABA'
                   % Load the events from the file(s).
                    for fileIndex = 1:fileCount
                        events(fileIndex).file_path = app.filePaths{fileIndex};

                        % Read BORIS xlsx file & format
                        T = readtable(app.filePaths{fileIndex});
                        T.Behavior = categorical(T.Behavior);
                        T.BehaviorType = categorical(T.BehaviorType);


                        %lowerbehavs = string(T.Behavior); % convert behaviors to string so it can be searched in case insensitive manner
                        % convert param_behavs to standard format (no caps, no spaces)
                        % alert user of differences
                        behaviorNames = cellstr(T.Behavior);
                        for i = 1:length(behaviorNames)
                            new_name = behaviorToFieldName(app,behaviorNames{i});
                            if ~strcmp(behaviorNames{i},new_name)
                                behaviorNames{i} = new_name;
                            end
                        end

                        % replace Behaviors in Table with formatted names
                        T.Behavior = categorical(behaviorNames);

                        % Get the start time and duration of all events for each type of behavior.
                        uniqueBehaviors = unique(T.Behavior);

                        % remove any <undefined> entries
                        % TODO: check what this does
                        uniqueBehaviors = uniqueBehaviors(~isundefined(uniqueBehaviors));

                        % check to see if any of the uniqueBehaviors are
                        % not found in param_behavs and alert user.
                        notfound = ~ismember(uniqueBehaviors,param_behavs);
                        InvalidNames = cellstr(uniqueBehaviors(notfound));
                        if ~isempty(InvalidNames)
                            uialert(app.UIFigure, ['The following behavior names were not found in your parameters file. Make sure to check spelling and formatting.' InvalidNames'], 'Invalid Behavior Names!');
                        end

                        % for b = 1:length(uniqueBehaviors)
                        %     behavior = char(uniqueBehaviors(b));
                        for b = 1:length(param_behavs)
                            behavior = char(param_behavs(b));

                            % Get the indices of the events for this behavior.
                            behaviorIdxs = T.Behavior==behavior; %TODO categorical searches are case sensitive!!
                            %behaviorIdxs = strcmpi(lowerbehavs, behavior);

                            if sum(behaviorIdxs)==0 % no events found
                                % Store the start times and durations for the behavior.
                                events(fileIndex).(behaviorToFieldName(app,behavior)) = [];
                            else
                                % Get the start times and durations at those indices.
                                
                                startTimes = T.Time(T.Behavior==behavior & T.BehaviorType=='START');
                                stopTimes = T.Time(T.Behavior==behavior & T.BehaviorType=='STOP');
                                
                                if iscellstr(startTimes) || isstring (startTimes)
                                startTimes = str2double(startTimes);
                                stopTimes = str2double(stopTimes);
                                end

                                % check to see if there are the same number
                                % of starts and stops
                                nStarts=length(startTimes);
                                nStops = length(stopTimes);

                                if nStarts > nStops
                                   % start w/o stop (most likely)
                                   if nStarts-nStops > 1
                                       % display error to user saying there
                                       % are multiple unclose events
                                   else 
                                       stopTimes(end+1) = app.endTime;
                                   end
                                elseif nStarts < nStops
                                    % stop w/o start (unlikely)
                                    if nStops-nStarts > 1
                                        % display error to user saying
                                        % there are multiple unstarted
                                        % events
                                    else
                                        startTimes = [app.startTime ; starTimes];
                                    end
                              
                                end
                                
                                durations = stopTimes - startTimes;
                                
                                % Could there be start or stops without a partner?

                                % Restrict to the events within the time window.
                                % Eliminate events that completely
                                % outside window (startTime>app.endTime | stopTime<app.startTimes)
                                idx = ones(1,length(startTimes));
                                for i = 1:length(startTimes)
                                    if startTimes(i) > app.endTime || stopTimes(i) < app.startTime
                                        % if event starts after app.endTime or ends before app.startTime, eliminate
                                        idx(i) = 0;
                                    elseif startTimes(i) < app.startTime && stopTimes(i) > app.endTime
                                        % if event starts before app.startTime and ends after app.endTime (spans
                                        % entire window), trim to window
                                        startTimes(i) = app.startTime;
                                        stopTimes(i) = app.endTime;
                                        durations(i) = app.endTime - app.startTime;
                                    elseif startTimes(i) < app.startTime && stopTimes(i) < app.endTime
                                        % if event start before app.startTime but ends before app.endTime, start
                                        % at app.startTime
                                        startTimes(i) = app.startTime;
                                        durations(i) = stopTimes(i)-app.startTime;
                                    elseif startTimes(i) < app.endTime && stopTimes(i) > app.endTime
                                        % if event starts before app.endTime and ends after app.endTime, set
                                        % end to app.endTime
                                        stopTimes(i) = app.endTime;
                                        durations(i) = app.endTime - startTimes(i);
                                    end
                                end
                                idx = logical(idx);
                                startTimes = startTimes(idx);
                                %stopTimes = stopTimes(idx);
                                durations = durations(idx);
                                
                                % Store the start times and durations for the behavior.
                                %events(fileIndex).(behaviorToFieldName(app,behavior)) = horzcat(startTimes(idxs), durations(idxs)); %#ok<AGROW>
                                events(fileIndex).(behaviorToFieldName(app,behavior)) = horzcat(startTimes, durations); %#ok<AGROW>
                            end

                        end
                    end

                    % Duplicate events for classes
                    behaviorClassNames = fieldnames(app.behaviorClasses);
                    for c = 1:length(behaviorClassNames)
                        behaviorClass = behaviorClassNames{c};
                        behaviors = app.behaviorClasses.(behaviorClass).behaviors;
                        %behaviorClass = ['class_' behaviorClass]; %#ok<AGROW>
                        for f = 1:length(events)
                            events(f).(behaviorClass) = []; %#ok<AGROW>

                            for b = 1:length(behaviors)
                                behavior = behaviors{b};
                                if isfield(events(f), behavior)
                                    behaviorEvents = events(f).(behavior);

                                    if ~isempty(behaviorEvents)
                                        events(f).(behaviorClass) = vertcat(events(f).(behaviorClass), behaviorEvents); %#ok<AGROW>
                                    end
                                end
                            end

                            events(f).(behaviorClass) = sortrows(events(f).(behaviorClass)); %#ok<AGROW>
                        end
                    end


            end

            %% Calculate the statistics for each behavior and class.
            stats = struct;
            behaviors = fieldnames(events);
            for i = 1:length(behaviors)
                behavior = behaviors{i};
                if ~strcmp(behavior, 'file_path')
                    stats.(behavior) = eventsStats(app, events, behavior);
                end
            end

            timeRange = [app.startTime app.endTime];
            timeRange(2) = timeRange(2) - timeRange(1);

            %% Save mat file

            % TODO: sanitize genotype for file name usage?
            filename = fullfile(app.savePath, [app.saveFname '.mat']);
            genotype = app.saveFname;
            params = app.behavTable;
            paramsFName = app.paramsFname;
            save(filename, 'genotype', 'timeRange', 'events', 'stats','params','paramsFName');


        end

        function out = eventsStats(app, events, behavior)
            % Compute the statistics for the events of the indicated behavior.

            totalDuration = app.endTime - app.startTime;
            timeRange = [app.startTime app.endTime];

            % Compute the bout frequencies and total times for each fly.
            for f = 1:length(events)
                % create bout_freq & total_time fields
                s.bout_freq(f) = 0;
                s.total_time(f) = 0;

                % determine if fly did this behavior. If not, set as empty matrix
                if isfield(events(f), behavior)
                    behaviorEvents = events(f).(behavior);
                else
                    behaviorEvents = [];
                end

                % Don't include bouts that are outside of the start or end time points.
                if ~isempty(behaviorEvents) && behaviorEvents(1, 1) < timeRange(1)
                    behaviorEvents = behaviorEvents(2:end, :);
                end
                if ~isempty(behaviorEvents) && behaviorEvents(end, 1) + behaviorEvents(end, 2) > timeRange(2)
                    behaviorEvents = behaviorEvents(1:(end-1), :);
                end

                % If there are still events, extract the bout frequency & total
                % time
                if ~isempty(behaviorEvents)
                    %             s.bout_freq(f) = 0;     % Note: this is redundant since already set above
                    %             s.total_time(f) = 0;
                    %         else
                    s.bout_freq(f) = size(behaviorEvents, 1);                       % number of events across flies
                    s.total_time(f) = sum(behaviorEvents(:, 2));                    % total time in sec behavior performed
                end
            end

            % Compute all of the derived stats.
            s.bout_freq_mean = mean(s.bout_freq);                                   % average number of events per expt
            s.bout_freq_median = median(s.bout_freq);                               % median number of events per expt
            s.bout_freq_std = std(s.bout_freq);                                     % standard deviation of num events/expt
            s.bout_freq_stderr = s.bout_freq_std ./ sqrt(length(s.bout_freq));      % standard error of num events/expt

            s.bout_rate = s.bout_freq / totalDuration * 60;                         % events per minute
            s.bout_rate_mean = mean(s.bout_rate);
            s.bout_rate_median = median(s.bout_rate);
            s.bout_rate_std = std(s.bout_rate);
            s.bout_rate_stderr = s.bout_rate_std ./ sqrt(length(s.bout_rate));

            s.bout_dur = s.total_time ./ s.bout_freq;                               % average duration of a bout
            s.bout_dur(isnan(s.bout_dur)) = 0;
            s.bout_dur_mean = mean(s.bout_dur);
            s.bout_dur_median = median(s.bout_dur);
            s.bout_dur_std = std(s.bout_dur);
            s.bout_dur_stderr = s.bout_dur_std ./ sqrt(length(s.bout_dur));

            s.total_time_mean = mean(s.total_time);                                 % total time performing behavior/expt
            s.total_time_meadian = median(s.total_time);
            s.total_time_std = std(s.total_time);
            s.total_time_stderr = s.total_time_std ./ sqrt(length(s.total_time));

            s.percent_time = s.total_time / totalDuration;                          % percent of time in expt performing behavior
            s.percent_time_mean = mean(s.percent_time);
            s.percent_time_median = median(s.percent_time);
            s.percent_time_std = std(s.percent_time);
            s.percent_time_stderr = s.percent_time_std ./ sqrt(length(s.percent_time));

            out = s;
        end


        function [behaviorClasses, behavTable, behavList] = import_behavparams(app,dirName,fileName)
            %IMPORT_BEHAVPARAMS This function imports behaviors and colors for grooming
            %plots
            %  Detailed explanation goes here
            % dirName = filepath where csv param file is located
            % fileName = name of csv params file to import


            %% Import csv file and make struct

            T = readtable(fullfile(dirName,fileName));

            T.BehaviorClass = categorical(T.BehaviorClass);

            % get BehaviorClasses and create structure
            behav_classes = cellstr(unique(T.BehaviorClass));    % behavior classes from 1st col
            
            % reorder behav_classes as specified in ClassOrder
            T = sortrows(T,{'ClassOrder','BehaviorOrder'},{'ascend','ascend'}); % sort rows by class and behavior


            % % Behavior Classes (this will be imported from a params file in future)
            %     behaviorClasses.head_cleaning = {'head_cleaning', 'antennal_cleaning', 'proboscis_cleaning', 'ventral_head'};
            %     behaviorClasses.front_leg_rubbing = {'front_leg_rubbing', 'b_1st_and_2nd_leg_rub', 'front_proximal_leg_rub'};
            %     behaviorClasses.wing_cleaning = {'wing_cleaning', 'ventral_wing', 'wing_blade', 'wing_hinge_slash_haltere'};
            %     behaviorClasses.back_leg_rubbing = {'back_leg_rubbing', 'proximal_leg_rub', 'b_2nd_and_3rd_leg_rub'};
            %     behaviorClasses.abdominal_cleaning = {'abdominal_cleaning', 'genital_cleaning'};
            %     behaviorClasses.other = {'ventral_front_cleaning', 'grooming_attempt', 'first_leg_notum', 'ventral_back_cleaning', ...
            %                              'back_leg_grooming_attempt', 'standing_still', 'on_back', 'slip_slash_jump', 'ventral_middle'};

            behaviorClasses = struct;
            behavList = {};

            for i = 1:length(behav_classes)
                bIdx =  find(T.BehaviorClass==behav_classes{i}==1);
                if ~isempty(bIdx)
                    % get behaviors for this class
                    behavs = T.Behavior(bIdx);                                               % get behaviors belonging to this class
              
                    
                    %[~,bind] = sort(T.BehaviorOrder(bIdx));     % necessary?                                 % get and sort behavior order
                    %behavs = behavs(bind);                      % necessary?                                   % reorder behaviors according to specified order
                    %behavs = reshape(behavs,1,size(behavs,1));  % necessary?                            % reshape into 1x... array

                    % get colors for behav class & individual behaviors
                    classcolor = [T.R1(bIdx(1)) T.G1(bIdx(1)) T.B1(bIdx(1))];
                    behavcolors = [T.R2(bIdx) T.G2(bIdx) T.B2(bIdx)];
                    %behavcolors = behavcolors(bind,:);  % sort to match behaviors, necessary?

                    % add to behaviorClasses structure
                    behaviorClasses.(behav_classes{i}).classcolor =  classcolor;
                    behaviorClasses.(behav_classes{i}).behaviors = behavs;
                    behaviorClasses.(behav_classes{i}).behaviorcolors = behavcolors;

                    % build temporary BList
                    blist = [];
                    blist{1,1} = behav_classes{i};
                    blist{1,2} = classcolor;
                    blist(2:1+length(behavs),1) = behavs;
                    for ii = 1:length(behavs)
                        blist{ii+1,2} = behavcolors(ii,:);
                    end

                    behavList = [behavList ; blist];

                end

            end

            % find repeated entries in behavList
            [~,stringIdx] = unique(behavList(:,1)); % behavList
            behavList = behavList(stringIdx,:);


            behavTable = T;
        end


        function fieldName = behaviorToFieldName(app,behaviorName)
            if iscellstr(behaviorName)
                fieldName = cell(1, length(behaviorName));
                for i = 1: length(behaviorName)
                    fieldName{i} = fieldToBehaviorName(app,behaviorName{i});
                end
            else
                fieldName = lower(strrep(strtrim(behaviorName), ' ', '_'));
                fieldName = strrep(fieldName, '/', '_slash_');
                fieldName = strrep(fieldName, '+', '_plus_');
                if ~isnan(str2double(fieldName(1)))
                    fieldName = ['b_' fieldName];
                end
            end
        end


        function behaviorName = fieldToBehaviorName(app,fieldName)
            if iscellstr(fieldName)
                behaviorName = cell(1, length(fieldName));
                for i = 1: length(fieldName)
                    behaviorName{i} = fieldToBehaviorName(app,fieldName{i});
                end
            else
                if strncmp(fieldName, 'b_', 2)
                    behaviorName = fieldName(3:end);
                else
                    behaviorName = fieldName;
                end
                behaviorName = strrep(behaviorName, '_slash_', '/');
                behaviorName = strrep(behaviorName, '_plus_', '+');
                behaviorName = strrep(behaviorName, '_', ' ');
                spaces = [0 find(behaviorName == ' ')];
                %behaviorName(spaces + 1) = upper(behaviorName(spaces + 1));
                slashes = [0 find(behaviorName == '/')];
                behaviorName(slashes + 1) = upper(behaviorName(slashes + 1));
                behaviorName = strrep(behaviorName, ' And ', ' and ');
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FilesforoneconditionListBox
        function FilesforoneconditionListBoxValueChanged(app, event)

            value = app.FilesforoneconditionListBox.Value;
            app.fIdx = find(strcmp(event.Source.Value,app.FilesforoneconditionListBox.Items)==1); % Update index of which file is selected
            %update_matfile_info(app);
        end

        % Button pushed function: AddfilesButton
        function AddfilesButtonPushed(app, event)

            % Prompt the user for the file(s) to use.
            [FileNames, dirName] = uigetfile({'*.txt;*.xlsx;*.csv', 'Text, Excel, or CSV Files (*.txt, *.xlsx, *.csv)'}, 'Pick one or more data files', 'MultiSelect', 'on');
            if isscalar(FileNames)
                return % the user cancelled
            end
            if iscell(FileNames)
                FilePaths = {};
                for fileName = FileNames
                    FilePaths = [FilePaths fullfile(dirName, char(fileName))]; %#ok<AGROW>
                end
                FilePaths = sort(FilePaths);
            else
                FilePaths = {fullfile(dirName, FileNames)};
                FileNames = {FileNames};
            end

            % Don't add files that are already in the list
            if ~isempty(app.FilesforoneconditionListBox.Items)
                fileNames2remove = ones(1,length(FileNames));
                for i = 1:length(FileNames) % check if each file name exists
                    if ismember(FileNames{i},app.FilesforoneconditionListBox.Items)
                        fileNames2remove(i) = 0;
                    end
                end
                fileNames2remove = logical(fileNames2remove);
                FileNames = FileNames(fileNames2remove);
                FilePaths = FilePaths(fileNames2remove);
            end

            app.fileNames = [app.fileNames FileNames];
            app.FilesforoneconditionListBox.Items = app.fileNames;
            app.filePaths = [app.filePaths FilePaths];
        end

        % Callback function
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            app.genotype = app.NameEditField.Value;
        end

        % Value changed function: MatfilenameEditField
        function MatfilenameEditFieldValueChanged(app, event)
            value = app.MatfilenameEditField.Value;

            badChar = '<>:"/\|?*';
            bad = ismember(badChar, value);
            if any(bad)
                msgbox('Filename cannot contain the following characters: <>:"/\|?*')
                app.MatfilenameEditField.FontColor = [1 0 0];
                app.saveFname = '';
            else
                app.saveFname = value;
            end

        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            %Prompt the user for the path to save mat file.
            savePath = uigetdir(cd,'Select folder for saving files');

            if savePath == 0 % user canceled
                app.SavepathEditField.Value = app.savePath;
            else
                app.savePath = savePath;
                app.SavepathEditField.Value = app.savePath;
            end
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
            value = app.FileTypeDropDown.Value;
            app.fileType = value;
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            value = app.StartEditField.Value;
            % start time cannot be negative or greater than endTime
            if value < 0
                msgbox('Start time cannot be negative')
                app.StartEditField.Value = 0;

            elseif value >= app.endTime
                msgbox('Start time must be less than end time ')
                app.StartEditField.Value = 0;

            else
                app.startTime = value;
            end

        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            value = app.EndEditField.Value;

            % end time cannot be less than start time

            if value <= app.startTime
                msgbox('End time must be greater than start time ')
                app.EndEditField.Value = 0;

            else
                app.endTime = value;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % clears all data except for save path
            app.data = [];
            app.saveFname = [];
            app.filePaths = [];
            app.FilesforoneconditionListBox.Items = {};
            app.fileNames = [];
            %app.fileType = [];
            %app.FileTypeDropDown.Value = 'Noldus';
            app.startTime = 0;
            app.endTime = 0;
            app.StartEditField.Value = 0;
            app.EndEditField.Value = 0;
            app.MatfilenameEditField.Value = '';
        end

        % Button pushed function: ProcessFilesButton
        function ProcessFilesButtonPushed(app, event)

            compileGroomingData_allcomp(app);
            uialert(app.UIFigure, [app.saveFname ' successfully written!'], 'Success');


        end

        % Button pushed function: LoadParamsButton
        function LoadParamsButtonPushed(app, event)
            [bfileName,bdirName] = uigetfile({'*.csv','csv files (*.csv)'}, 'Select behavior param csv file');
            app.paramsFname = fullfile(bdirName,bfileName);
            [behaviorClasses B BList] = import_behavparams(app,bdirName,bfileName);
            app.behaviorClasses = behaviorClasses;
            app.behavTable = B;
            app.behavList = BList;
            app.LoadParamsButton.BackgroundColor = [0 1 0];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessGroomingDataFilesPanel = uipanel(app.UIFigure);
            app.ProcessGroomingDataFilesPanel.Title = 'Process Grooming Data Files';
            app.StartEditFieldLabel = uilabel(app.ProcessGroomingDataFilesPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.ProcessGroomingDataFilesPanel, 'numeric');
            app.StartEditField.Limits = [0 Inf];
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.ProcessGroomingDataFilesPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.ProcessGroomingDataFilesPanel, 'numeric');
            app.EndEditField.Limits = [0 Inf];
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.TimeRangesecLabel = uilabel(app.ProcessGroomingDataFilesPanel);
            app.TimeRangesecLabel.Text = 'Time Range (sec)';
            app.AddfilesButton = uibutton(app.ProcessGroomingDataFilesPanel, 'push');
            app.AddfilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddfilesButtonPushed, true);
            app.AddfilesButton.Text = 'Add files';
            app.FilesforoneconditionListBoxLabel = uilabel(app.ProcessGroomingDataFilesPanel);
            app.FilesforoneconditionListBoxLabel.Text = 'Files for one condition';
            app.FilesforoneconditionListBox = uilistbox(app.ProcessGroomingDataFilesPanel);
            app.FilesforoneconditionListBox.Items = {};
            app.FilesforoneconditionListBox.Multiselect = 'on';
            app.FilesforoneconditionListBox.ValueChangedFcn = createCallbackFcn(app, @FilesforoneconditionListBoxValueChanged, true);
            app.FilesforoneconditionListBox.Value = {};
            app.FileTypeDropDownLabel = uilabel(app.ProcessGroomingDataFilesPanel);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.ProcessGroomingDataFilesPanel);
            app.FileTypeDropDown.Items = {'Noldus', 'VCode', 'BORIS'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = 'Noldus';
            app.ProcessFilesButton = uibutton(app.ProcessGroomingDataFilesPanel, 'push');
            app.ProcessFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessFilesButtonPushed, true);
            app.ProcessFilesButton.Text = 'Process Files';
            app.ResetButton = uibutton(app.ProcessGroomingDataFilesPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SetButton = uibutton(app.ProcessGroomingDataFilesPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.MatfilenameEditFieldLabel = uilabel(app.ProcessGroomingDataFilesPanel);
            app.MatfilenameEditFieldLabel.Text = 'Mat file name:';
            app.MatfilenameEditField = uieditfield(app.ProcessGroomingDataFilesPanel, 'text');
            app.MatfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @MatfilenameEditFieldValueChanged, true);
            app.SavepathEditFieldLabel = uilabel(app.ProcessGroomingDataFilesPanel);
            app.SavepathEditFieldLabel.Text = 'Save path:';
            app.SavepathEditField = uieditfield(app.ProcessGroomingDataFilesPanel, 'text');
            app.SavepathEditField.Editable = 'off';
            app.LoadParamsButton = uibutton(app.ProcessGroomingDataFilesPanel, 'push');
            app.LoadParamsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParamsButtonPushed, true);
            app.LoadParamsButton.Text = 'Load Params';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_grooming

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end